classdef Analysis_environment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Analysis_Environment            matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadprocessdataTab              matlab.ui.container.Tab
        ReadrawdataButton               matlab.ui.control.Button
        filetype                        matlab.ui.control.DropDown
        DatacolumnsEditFieldLabel       matlab.ui.control.Label
        DatacolumnsEditField            matlab.ui.control.EditField
        experimentnameEditFieldLabel    matlab.ui.control.Label
        experimentnameEditField         matlab.ui.control.EditField
        measurementnameEditFieldLabel   matlab.ui.control.Label
        measurementnameEditField        matlab.ui.control.EditField
        measurementdateEditFieldLabel   matlab.ui.control.Label
        measurementdateEditField        matlab.ui.control.EditField
        FiletypeLabel                   matlab.ui.control.Label
        MEALayoutEditFieldLabel         matlab.ui.control.Label
        MEALayoutEditField              matlab.ui.control.EditField
        DatatypeButtonGroup             matlab.ui.container.ButtonGroup
        MEAButton                       matlab.ui.control.RadioButton
        OtherButton                     matlab.ui.control.RadioButton
        MEAelectrodesEditFieldLabel     matlab.ui.control.Label
        MEAelectrodesEditField          matlab.ui.control.EditField
        ProcessdatafilteringPanel       matlab.ui.container.Panel
        FilterdataButton                matlab.ui.control.Button
        filenumsFilter_Label            matlab.ui.control.Label
        filenumsFilter                  matlab.ui.control.EditField
        columnsFilter_Label             matlab.ui.control.Label
        columnsFilter                   matlab.ui.control.EditField
        DatatofilterButtonGroup         matlab.ui.container.ButtonGroup
        FilterAllButton                 matlab.ui.control.RadioButton
        FilterChooseButton              matlab.ui.control.RadioButton
        filter1EditFieldLabel           matlab.ui.control.Label
        filter1EditField                matlab.ui.control.EditField
        filter2EditFieldLabel           matlab.ui.control.Label
        filter2EditField                matlab.ui.control.EditField
        FilterwindowsizesLabel          matlab.ui.control.Label
        OverwriteDataCheckBox           matlab.ui.control.CheckBox
        medfilt1filterorderNLabel       matlab.ui.control.Label
        Order2ndfiltersmoothdataymed1movmeann_order_filtLabel  matlab.ui.control.Label
        Noticecurrentlyonlyh5andabfsupportedLabel  matlab.ui.control.Label
        SethypoxiainfotoDataInfoPanel   matlab.ui.container.Panel
        hypoxia_datatime_textLabel      matlab.ui.control.Label
        FindhypoxiaindexesButton        matlab.ui.control.Button
        hypoxia_start_indexEditFieldLabel  matlab.ui.control.Label
        hypoxia_start_indexEditField    matlab.ui.control.EditField
        hypoxia_end_indexEditFieldLabel  matlab.ui.control.Label
        hypoxia_end_indexEditField      matlab.ui.control.EditField
        hypoxia_end_datetimeLabel       matlab.ui.control.Label
        hypoxia_end_datetimeEditField   matlab.ui.control.EditField
        hypoxia_start_datetimeEditFieldLabel  matlab.ui.control.Label
        hypoxia_start_datetimeEditField  matlab.ui.control.EditField
        SethypoxiaButtonGroup           matlab.ui.container.ButtonGroup
        fileindexesButton               matlab.ui.control.RadioButton
        datetimesButton                 matlab.ui.control.RadioButton
        SethypoxiaButton                matlab.ui.control.Button
        EditdataPanel                   matlab.ui.container.Panel
        SignalTypeDropDown              matlab.ui.control.DropDown
        set_signal_typeButton           matlab.ui.control.Button
        turn_signalButton               matlab.ui.control.Button
        remove_filenumsEditFieldLabel   matlab.ui.control.Label
        remove_filenumsEditField        matlab.ui.control.EditField
        remove_columnsEditFieldLabel    matlab.ui.control.Label
        remove_columnsEditField         matlab.ui.control.EditField
        removedataButton                matlab.ui.control.Button
        PeakHandlingTab                 matlab.ui.container.Tab
        ChoosepeakstohandleButtonGroup  matlab.ui.container.ButtonGroup
        highpeaksButton                 matlab.ui.control.RadioButton
        lowpeaksButton                  matlab.ui.control.RadioButton
        bothButton                      matlab.ui.control.RadioButton
        filenumsPeakHandlingEditField_Label  matlab.ui.control.Label
        filenumsPeakHandlingEditField   matlab.ui.control.EditField
        columnsPeakHandlingEditField_Label  matlab.ui.control.Label
        columnsPeakHandlingEditField    matlab.ui.control.EditField
        RulesandfindpeaksPanel          matlab.ui.container.Panel
        TimerangeEditFieldLabel         matlab.ui.control.Label
        TimerangeEditField              matlab.ui.control.EditField
        find_local_maximaButton         matlab.ui.control.Button
        find_peaks_with_rulesButton     matlab.ui.control.Button
        MaxBPMLabel                     matlab.ui.control.Label
        MaxBPMEditField                 matlab.ui.control.EditField
        MinPeakDistancemsLabel          matlab.ui.control.Label
        MinPeakDistancemsEditField      matlab.ui.control.EditField
        MinPeakAmplitudeabsLabel        matlab.ui.control.Label
        MinPeakAmplitudeabsEditField    matlab.ui.control.EditField
        DeletepeaksPanel                matlab.ui.container.Panel
        delete_all_peaksButton          matlab.ui.control.Button
        delete_peaks_with_peaknumberButton  matlab.ui.control.Button
        peaknumbersEditField            matlab.ui.control.EditField
        peaknumbersEditField_Label      matlab.ui.control.Label
        delete_peaks_with_timeButton    matlab.ui.control.Button
        delete_peaks_with_time_Label    matlab.ui.control.Label
        timerangeEditField              matlab.ui.control.EditField
        delete_peaks_belowButton        matlab.ui.control.Button
        delete_peaks_below_Label        matlab.ui.control.Label
        minAmplitudeEditField           matlab.ui.control.EditField
        delete_peaks_aboveButton        matlab.ui.control.Button
        delete_peaks_above_Label        matlab.ui.control.Label
        maxAmplitudeEditField           matlab.ui.control.EditField
        delete_first_peakButton         matlab.ui.control.Button
        delete_last_peakButton          matlab.ui.control.Button
        tarvitaankoPanel                matlab.ui.container.Panel
        set_main_peaksButton            matlab.ui.control.Button
        define_other_peaks_from_mainButton  matlab.ui.control.Button
        addpeaks_in_time_rangeButton    matlab.ui.control.Button
        AddpeaksPanel                   matlab.ui.container.Panel
        add_high_peak_from_lowButton    matlab.ui.control.Button
        tp_forwEditFieldLabel           matlab.ui.control.Label
        tp_forwEditField                matlab.ui.control.NumericEditField
        tp_backEditFieldLabel           matlab.ui.control.Label
        tp_backEditField                matlab.ui.control.NumericEditField
        CheckandModifypeaksPanel        matlab.ui.container.Panel
        Trim_found_peaksButton          matlab.ui.control.Button
        check_peak_distances_from_edgesButton  matlab.ui.control.Button
        AnalysisTab                     matlab.ui.container.Tab
        DataPeaks_summaryPanel          matlab.ui.container.Panel
        FPstarttdepButton               matlab.ui.control.Button
        PlotDataPeaks_summaryButton     matlab.ui.control.Button
        PlotDataPeaks_summarysettingsButtonGroup  matlab.ui.container.ButtonGroup
        Default_DataPeaks_summaryButton  matlab.ui.control.RadioButton
        Choose_DataPeaks_summary_Button  matlab.ui.control.RadioButton
        Normalized_plotCheckBox         matlab.ui.control.CheckBox
        FPDccorrectionDropDownLabel     matlab.ui.control.Label
        FPDccorrectionDropDown          matlab.ui.control.DropDown
        timeunitDropDownLabel           matlab.ui.control.Label
        timeunitDropDown                matlab.ui.control.DropDown
        yaxisscaleEditFieldLabel        matlab.ui.control.Label
        yaxisscaleEditField             matlab.ui.control.EditField
        yaxisscaleO2EditFieldLabel      matlab.ui.control.Label
        yaxisscaleO2EditField           matlab.ui.control.EditField
        first_peak_typeDropDownLabel    matlab.ui.control.Label
        first_peak_typeDropDown         matlab.ui.control.DropDown
        fp_start_thresh_pctSpinnerLabel  matlab.ui.control.Label
        fp_start_thresh_pctSpinner      matlab.ui.control.Spinner
        firstfilterparameterSpinnerLabel  matlab.ui.control.Label
        firstfilterparameterSpinner     matlab.ui.control.Spinner
        firstfiltertypeDropDownLabel    matlab.ui.control.Label
        firstfiltertypeDropDown         matlab.ui.control.DropDown
        FlatpeakFPDButton               matlab.ui.control.Button
        ndfiltertypeDropDownLabel       matlab.ui.control.Label
        ndfiltertypeDropDown            matlab.ui.control.DropDown
        fp_end_thresh_pctSpinnerLabel   matlab.ui.control.Label
        fp_end_thresh_pctSpinner        matlab.ui.control.Spinner
        ndfilterparameterSpinnerLabel   matlab.ui.control.Label
        ndfilterparameterSpinner        matlab.ui.control.Spinner
        nd_peak_typeDropDownLabel       matlab.ui.control.Label
        nd_peak_typeDropDown            matlab.ui.control.DropDown
        ndpeaktimewindowEditFieldLabel  matlab.ui.control.Label
        ndpeaktimewindowEditField       matlab.ui.control.EditField
        drawpeaktimewindowButton        matlab.ui.control.Button
        Norm_indexesEditFieldLabel      matlab.ui.control.Label
        Norm_indexesEditField           matlab.ui.control.EditField
        FPDtdepPanel                    matlab.ui.container.Panel
        PlotsignalFPDButton             matlab.ui.control.Button
        PlottdepButton                  matlab.ui.control.Button
        BPM_summaryPanel                matlab.ui.container.Panel
        QuickPlotData_BPM_summaryButton  matlab.ui.control.Button
        SummaryplotButtonGroup          matlab.ui.container.ButtonGroup
        BPMButton                       matlab.ui.control.RadioButton
        AmplitudeButton                 matlab.ui.control.RadioButton
        checkirregularbeatingButton     matlab.ui.control.Button
        IrregularlevelLabel             matlab.ui.control.Label
        IrregularlevelEditField         matlab.ui.control.EditField
        CreateBPMsummaryButton          matlab.ui.control.Button
        plot_indivual_irregular_filesButton  matlab.ui.control.Button
        DataPeaksPanel                  matlab.ui.container.Panel
        CreateDataPeaksButton           matlab.ui.control.Button
        CreateDataPeaks_meanButton      matlab.ui.control.Button
        t_backEditFieldLabel            matlab.ui.control.Label
        t_backEditField                 matlab.ui.control.NumericEditField
        t_forwEditFieldLabel            matlab.ui.control.Label
        t_forwEditField                 matlab.ui.control.NumericEditField
        everynthEditFieldLabel          matlab.ui.control.Label
        everynthEditField               matlab.ui.control.NumericEditField
        checkDataPeaksButton            matlab.ui.control.Button
        PlottingTab                     matlab.ui.container.Tab
        WritevariablestoplotPanel       matlab.ui.container.Panel
        PlotButton_2                    matlab.ui.control.Button
        xvariableEditFieldLabel         matlab.ui.control.Label
        xvariableEditField              matlab.ui.control.EditField
        yvariableEditFieldLabel         matlab.ui.control.Label
        yvariableEditField              matlab.ui.control.EditField
        SummaryofDataPeaksFPDdepoltimePanel  matlab.ui.container.Panel
        PlotDataPeaks_summaryButton_2   matlab.ui.control.Button
        PlotDataPeaks_summarysettingsButtonGroup_2  matlab.ui.container.ButtonGroup
        Default_DataPeaks_summaryButton_2  matlab.ui.control.RadioButton
        Choose_DataPeaks_summary_Button_2  matlab.ui.control.RadioButton
        Normalized_plotCheckBox_2       matlab.ui.control.CheckBox
        FPDccorrectionDropDown_2Label   matlab.ui.control.Label
        FPDccorrectionDropDown_2        matlab.ui.control.DropDown
        timeunitDropDown_2Label         matlab.ui.control.Label
        timeunitDropDown_2              matlab.ui.control.DropDown
        yaxisscaleEditField_2Label      matlab.ui.control.Label
        yaxisscaleEditField_2           matlab.ui.control.EditField
        yaxisscaleO2EditField_2Label    matlab.ui.control.Label
        yaxisscaleO2EditField_2         matlab.ui.control.EditField
        Norm_indexesEditField_2Label    matlab.ui.control.Label
        Norm_indexesEditField_2         matlab.ui.control.EditField
        PlotsignalFPDButton_2           matlab.ui.control.Button
        PlottdepButton_2                matlab.ui.control.Button
        PlotFPsummaryButton             matlab.ui.control.Button
        SummaryofavgbeatingPanel        matlab.ui.container.Panel
        SummaryplotButtonGroup_2        matlab.ui.container.ButtonGroup
        BPMButton_2                     matlab.ui.control.RadioButton
        AmplitudeButton_2               matlab.ui.control.RadioButton
        PlotButton_4                    matlab.ui.control.Button
        DataPeaksPanel_2                matlab.ui.container.Panel
        PlotDataPeaks_meanButton        matlab.ui.control.Button
        checkDataPeaksButton_2          matlab.ui.control.Button
        t_backEditField_2Label          matlab.ui.control.Label
        t_backEditField_2               matlab.ui.control.NumericEditField
        t_forwEditField_2Label          matlab.ui.control.Label
        t_forwEditField_2               matlab.ui.control.NumericEditField
        everynthEditField_2Label        matlab.ui.control.Label
        everynthEditField_2             matlab.ui.control.NumericEditField
        PlotDataPeaksingivenfilenumsandcolumnsButton  matlab.ui.control.Button
        PlotpeakorbeatingfrequencyPanel  matlab.ui.container.Panel
        PlotpeakfrequencyButton         matlab.ui.control.Button
        PlotFrequency_settings          matlab.ui.container.ButtonGroup
        Default_FreqButton              matlab.ui.control.RadioButton
        Choose_FreqButton               matlab.ui.control.RadioButton
        Normalized_plotCheckBox_3       matlab.ui.control.CheckBox
        timeunitDropDown_3Label         matlab.ui.control.Label
        timeunitDropDown_3              matlab.ui.control.DropDown
        Norm_indexesEditField_3Label    matlab.ui.control.Label
        Norm_indexesEditField_3         matlab.ui.control.EditField
        plottocurrentfigureCheckBox     matlab.ui.control.CheckBox
        yaxisunitDropDownLabel          matlab.ui.control.Label
        yaxisunitDropDown               matlab.ui.control.DropDown
        shifttimeinsecEditFieldLabel    matlab.ui.control.Label
        shifttimeinsecEditField         matlab.ui.control.EditField
        GeneralPanel                    matlab.ui.container.Panel
        ChoosedataforplottingPanel      matlab.ui.container.Panel
        IcludepeaknumbersinplotCheckBox  matlab.ui.control.CheckBox
        PlotdatacolumnstosameCheckBox   matlab.ui.control.CheckBox
        filenumsEditField_Label         matlab.ui.control.Label
        filenumsEditField               matlab.ui.control.EditField
        columnsEditField_Label          matlab.ui.control.Label
        columnsEditField                matlab.ui.control.EditField
        PlotdatafilestosameCheckBox     matlab.ui.control.CheckBox
        DatacolumnsSwitchLabel          matlab.ui.control.Label
        DatacolumnsSwitch               matlab.ui.control.RockerSwitch
        SaveloadandclearPanel           matlab.ui.container.Panel
        ChooseFolderButton              matlab.ui.control.Button
        SavingfolderLabel               matlab.ui.control.Label
        SaveAllButton                   matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        SaveButton                      matlab.ui.control.Button
        clearallexceptButton            matlab.ui.control.Button
        notremoveEditField              matlab.ui.control.EditField
        LoadmatfilesButton              matlab.ui.control.Button
        ClearcommandwindowButton        matlab.ui.control.Button
        SaveDatawithoutaskingCheckBox   matlab.ui.control.CheckBox
        PlotPanel                       matlab.ui.container.Panel
        PlotRAWButton                   matlab.ui.control.Button
        PlotDataButton                  matlab.ui.control.Button
        CloseallButton                  matlab.ui.control.Button
        PlotsignalupsidedownButton      matlab.ui.control.Button
        plot_every10th_signal           matlab.ui.control.Button
        plot_random_file                matlab.ui.control.Button
        amount_of_random_files          matlab.ui.control.NumericEditField
        plotsLabel                      matlab.ui.control.Label
        SizeButtonGroup                 matlab.ui.container.ButtonGroup
        DefaultsizeButton               matlab.ui.control.RadioButton
        FullscreenButton                matlab.ui.control.RadioButton
        HalfscreenButton                matlab.ui.control.RadioButton
        FigtocloseEditFieldLabel        matlab.ui.control.Label
        FigtocloseEditField             matlab.ui.control.EditField
        CopyFiguretoclipboardButton     matlab.ui.control.Button
        PlotRawinMEAlayoutButton        matlab.ui.control.Button
        ClosecurrentfigureButton        matlab.ui.control.Button
        PlotnextDataButton              matlab.ui.control.Button
        Plot_next_raw                   matlab.ui.control.Button
        deletedatalinesEditFieldLabel   matlab.ui.control.Label
        deletedatalinesEditField        matlab.ui.control.EditField
        deletedatafromplotButton        matlab.ui.control.Button
        UpdatefilenumsfromworkspaceButton  matlab.ui.control.Button
        ListfilenamesoffilenumsButton   matlab.ui.control.Button
        PrintsfilenamesonworkspaceLabel  matlab.ui.control.Label
        updateData_BPMButton            matlab.ui.control.Button
        updateDataInfoButton            matlab.ui.control.Button
        ClosechosenfigsButton           matlab.ui.control.Button
        printamoutofpeaksfoundButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        % when reading from panels
        % str2num is used as can be in vector format 2:5 --> str2double does not work!
        filenums % filenumber to handle
        datacolumns % datacolumns to handle
        whichpeak % high, low, or both peaks
        maxBPMinpeakfind % max BPM value used in peak finding (affecting minimum peak distance)
        minAmptofind % minimum (absolute) value for peak finding
    end
    
    methods (Access = private)
        
        
        function [filenums,cols,which_peaks_handled] = read_values_from_app_peak_handling(app)
            nargoutchk(0,3)
            % now reading these filenum and cols values from peak handling parameters
            filenums = str2num(app.filenumsPeakHandlingEditField.Value);
            cols = str2num(app.columnsPeakHandlingEditField.Value);
            switch app.whichpeak
                case 'low peaks'
                    which_peaks_handled = 0;
                    disp('Handling low peaks')
                case 'high peaks'
                    which_peaks_handled = 1;
                    disp('Handling high peaks')
                case 'both'
                    which_peaks_handled = 2;
                    disp('Handling both high and low peaks')
            end
        end
        
        function [filenums,cols] = read_filenums_and_datacolumns_parent(app)
            nargoutchk(0,2)
            filenums = str2num(app.filenumsEditField.Value);
            cols = str2num(app.columnsEditField.Value);
            if any(filenums == inf)
                % set last index to last file index
                files_amount = evalin('base','DataInfo.files_amount');
                if length(filenums) == 1
                    filenums = 1:files_amount;
                else
                    filenums = sort(filenums);
                    filenums(end) = files_amount;
                end
            end
            if any(cols == inf)
                % set last index to last column index
                datacolumns = evalin('base','DataInfo.datacol_numbers');
                if length(cols) == 1
                    cols = 1:length(datacolumns);
                else
                    cols = sort(cols);
                    cols(end) = length(datacolumns);
                end
            end
        end
        
        
        function hfig = create_figure(app)
            nargoutchk(0,1)
            switch app.SizeButtonGroup.SelectedObject.Text
                case  'Default size'
                    hfig = figure;
                case 'Full screen'
                    fig_full
                case 'Half screen'
                    fig_half
            end
        end
        function out  = read_variable_from_ws(~, ws_variable)
            % out  = read_variable_from_ws(app, ws_variable)
            % DataInfo = read_variable_from_ws(app,'DataInfo');
            try
                out = evalin('base',ws_variable);
            catch
                warning([ws_variable, ' not found on workspace! Set to empty.'])
                out = [];
            end
        end
        function update_ws_variable(~, ws_variable_name, updated_variable)
            % update_ws_variable(app, 'ws_variable_name', updated_variable)
            % update_ws_variable(app, 'Data', Data)
            try
                assignin('base', ws_variable_name, updated_variable)
            catch
                error([ws_variable_name, ' on workspace could not be not updated!'])
            end
        end
        
        function [hypoxia_start_datetime, hypoxia_end_datetime] = read_hypoxia_start_and_end_datetime(app)
            try
                hypoxia_start_datetime = datetime(app.hypoxia_start_datetimeEditField.Value,'InputFormat','yyyy-MM-dd HH:mm:ss');
            catch
                hypoxia_start_datetime = datetime(app.hypoxia_start_datetimeEditField.Value,'InputFormat','yyyy-MM-dd HH:mm');
            end
            try
                hypoxia_end_datetime = datetime(app.hypoxia_end_datetimeEditField.Value,'InputFormat','yyyy-MM-dd HH:mm:ss');
            catch
                hypoxia_end_datetime = datetime(app.hypoxia_end_datetimeEditField.Value,'InputFormat','yyyy-MM-dd HH:mm');
            end
        end
        
        function [hypoxia_start_index, hypoxia_end_index] = get_hypoxia_start_and_end_file_indexes...
                (~, DataInfo, hypoxia_start_datetime, hypoxia_end_datetime)
            % NOTICE: now this is not really used!
            % get file indexes that are last indexes before (or at) given datetime(s)
            hypoxia_start_index = find(DataInfo.measurement_time.datetime <= hypoxia_start_datetime,1,'last');
            hypoxia_end_index = find(DataInfo.measurement_time.datetime <= hypoxia_end_datetime,1,'last');
        end
        
        function [hypoxia_start_index, hypoxia_end_index] = read_hypoxia_start_and_end_indexes(app)
            hypoxia_start_index = str2num(app.hypoxia_start_indexEditField.Value);
            hypoxia_end_index = str2num(app.hypoxia_end_indexEditField.Value);
            % taking only first index if multiple given
            hypoxia_start_index = hypoxia_start_index (1);
            hypoxia_end_index = hypoxia_end_index(1);
            % check input indexes
            if hypoxia_end_index < hypoxia_start_index
                disp('Start index latter than end index --> changing order')
                temp = hypoxia_start_index;
                hypoxia_start_index = hypoxia_end_index;
                hypoxia_end_index = temp;
            end
            if hypoxia_end_index == hypoxia_start_index
                disp('Start index same than end index: set end index to Inf')
                hypoxia_end_index = inf;
            end
            if hypoxia_end_index == inf
                % hypoxia end index set to last index
                temp = read_variable_from_ws(app, 'DataInfo.files_amount');
                hypoxia_end_index = temp;
            end
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % str2num is used as can be in vector format 2:5 --> str2double does not work!
            app.filenums = str2num(app.filenumsEditField.Value);
            try
                temp = evalin('base','DataInfo.datacol_numbers');
                disp('DataInfo.datacol_numbers found on workspace, setting datacols based on that')
                datacols = 1:length(temp);
            catch
                datacols = 1;
            end
            app.columnsEditField.Value = num2str(datacols);
            app.datacolumns = datacols;
            app.columnsPeakHandlingEditField.Value = num2str(datacols);
            app.filenumsPeakHandlingEditField.Value = app.filenumsEditField.Value;
            % hide at the begin as "All" chosen
            app.columnsEditField_Label.Visible = 'off';
            app.columnsEditField.Visible = 'off';
            % columnsEditFieldValueChanged(app, event) % idea was to updats datacolumns, no event on startup!
            
            app.whichpeak = app.ChoosepeakstohandleButtonGroup.SelectedObject.Text;
            app.maxBPMinpeakfind = str2num(app.MaxBPMEditField.Value);
            app.MinPeakDistancemsEditField.Value = num2str(60/app.maxBPMinpeakfind*1e3);
            try
                minpeakval = evalin('base','DataInfo.Rule.MinPeakValue');
            catch
                disp('No DataInfo.Rule.MinPeakValue found on workspace')
                minpeakval = 2.5e-5;
                disp(['Setting default value to min peak value: ',num2str(minpeakval)])
            end
            app.MinPeakAmplitudeabsEditField.Value = num2str(minpeakval);
            app.minAmplitudeEditField.Value = num2str(minpeakval);
            app.maxAmplitudeEditField.Value = num2str(minpeakval);
            app.minAmptofind = minpeakval;
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            disp(['Plotting data'])
            disp(['File indexes: ',num2str(filenums)])
            disp(['Datacolumn indexes: ',num2str(cols)])
            if isempty(filenums) % || isempty(cols)
                warning('Empty file index given, returning without plotting')
                return
            end
            plotting_peak_numbers = app.IcludepeaknumbersinplotCheckBox.Value;
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            plotting_data_files_to_same = app.PlotdatafilestosameCheckBox.Value;
            if length(filenums) == 1
                % if only one filenums is chosen, make plotting_data_files_to_same = 0,
                % no matter what is app.PlotdatafilestosameCheckBox.Value;
                plotting_data_files_to_same = 0;
            end
            dat = read_variable_from_ws(app,'Data');
            %             try
            %                 dat = evalin('base','Data');
            %             catch
            %                 error('No Data found')
            %             end
            
            datbpm = read_variable_from_ws(app,'Data_BPM');
            if isempty(datbpm) % if datbpm empty, not peaks found
                bpm_found = 0;
                disp('No peaks Data_BPM found, plotting only raw data')
            else
                bpm_found = 1;
            end
            %             try
            %                 datbpm = evalin('base','Data_BPM');
            %                 bpm_found = 1;
            %             catch
            %                 bpm_found = 0;
            %                 disp('No peaks Data_BPM found, plotting only raw data')
            %             end
            
            datainfo = read_variable_from_ws(app,'DataInfo');
            %             try
            %                 datainfo = evalin('base','DataInfo');
            %             catch
            %                 error('No DataInfo found')
            %             end
            
            if plotting_data_files_to_same == 0
                hfigs=[];
                for kk = 1:length(filenums)
                    hfigs(end+1,1) = create_figure(app);
                    % pause(0.2)
                    if bpm_found == 1 % && plotting_peak_numbers == 1
                        PLOT_DataWithPeaks_no_own_fig(dat,datbpm,filenums(kk),cols,plotting_peak_numbers,plotting_data_columns_to_same)
                    else
                        PLOT_DataOnly_no_own_figure(dat,filenums(kk),cols,plotting_data_columns_to_same);
                    end
                    zoom on
                end
                pause(0.2)
                for kk=length(hfigs):-1:1
                    figure(hfigs(kk,1))
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else % plot all filenums to same figure
                [fig_parameters] = cal_subfig_parameters(cols);
                sub_fig_rows=fig_parameters(1);
                sub_fig_cols=fig_parameters(2);
                extra_in_x_axis = 0.2; % for peak numbers
                
                if plotting_data_columns_to_same == 0
                    data_with_legend = [];
                    legs = cell(length(filenums),length(cols));
                else % all plots to one figure without subplots
                    legs = [];
                    data_with_legend = [];
                end
                for kk = 1:length(filenums)
                    file_index = filenums(kk);
                    if kk == 1
                        % figure size based on chosen
                        hfig = create_figure(app);
                        % pause(0.2)
                    end
                    % assuming data length (#rows) to be same in each data column in one file index
                    time = 0:1/datainfo.framerate(file_index):...
                        (length(dat{file_index}.data(:,1))-1)/datainfo.framerate(file_index);
                    for pp = 1:length(cols)
                        col_index = cols(pp);
                        try
                            leg_start = ['File#', num2str(file_index),'(t=',...
                                num2str(round(datainfo.measurement_time.time_sec(file_index)/3600,1)),'h)'];
                        catch
                            leg_start = ['File#', num2str(file_index)];
                        end
                        if plotting_data_columns_to_same == 0 || length(cols) < 2
                            % if all data columns are plotted to same figure --> no subplots needed
                            % otherwise, plot subplots based on amount of chosen datacolumns
                            subplot(sub_fig_rows,sub_fig_cols,pp)
                            if isfield(datainfo,'MEA_electrode_numbers')
                                title(['El#',num2str(datainfo.MEA_electrode_numbers(col_index))]);
                            else
                                title(['Col#',num2str(datainfo.datacol_numbers(col_index))]);
                            end
                            data_with_legend(kk,pp) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            % legend can be shorter as subplot title tells data column info
                            legs{kk,pp} = [leg_start];% ,', file_index=',num2str(file_index)];
                        else % no subplots: include el/col# in legend
                            data_with_legend(end+1) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            if isfield(datainfo,'MEA_electrode_numbers')
                                legs{end+1} = [leg_start,': El#',num2str(datainfo.MEA_electrode_numbers(col_index))];
                            else
                                legs{end+1} = [leg_start,': Col#',num2str(datainfo.datacol_numbers(col_index))];
                            end
                            
                        end
                        hold all
                        
                        
                        if bpm_found == 1 % plotting possible peaks
                            try
                                peak_times = time(datbpm{file_index,1}.peak_locations_high{col_index});
                                peak_values = datbpm{file_index,1}.peak_values_high{col_index};
                                plot(peak_times, peak_values,'ro')
                                highpeak_amount = length(peak_values);
                                % if include peak numbers in plot
                                if plotting_peak_numbers == 1
                                    text(peak_times+extra_in_x_axis,peak_values,...
                                        num2str((1:numel(peak_times))'))
                                end
                            catch %  ('no high peaks found')
                                highpeak_amount = 0;
                            end
                            try
                                peak_times = time(datbpm{file_index,1}.peak_locations_low{col_index});
                                peak_values = datbpm{file_index,1}.peak_values_low{col_index};
                                plot(peak_times, peak_values,'x','color',[0 .5 0.])
                                lowpeak_amount = length(peak_values);
                                % if include peak numbers in plot
                                if plotting_peak_numbers == 1
                                    text(peak_times+extra_in_x_axis,peak_values,...
                                        num2str((1:numel(peak_times))'))
                                end
                            catch %  ('no low peaks found')
                                lowpeak_amount = 0;
                            end
                        end % end: % plotting possible peaks
                        axis tight
                    end % end pp = 1:length(cols)
                end % end for kk = 1:length(filenums)
                sgtitle([datainfo.experiment_name, ' - ', datainfo.measurement_name],...
                    'interpreter','none','fontsize',12)
                if plotting_data_columns_to_same == 0
                    % each subplots with own legends
                    for pp = 1:length(cols)
                        subplot(sub_fig_rows,sub_fig_cols,pp)
                        if length(data_with_legend(:,pp)) > 3
                            % if length(legs) > 3 % legend text in two columns if more than 3 data
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none','NumColumns',2)
                        else
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none')
                        end
                    end
                else
                    if length(data_with_legend) > 3
                        % if length(legs) > 3 % legend text in two columns if more than 3 data
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none','NumColumns',2)
                    else
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none')
                    end
                    
                end
                zoom on
            end % end_: plot all filenums to same figure
            
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            disp('Closing all figures')
            close all
        end

        % Callback function
        function SaveData_BPMButtonPushed(app, event)
            disp('Saving Data_BPM')
            DataInfo = evalin('base','DataInfo');
            Data_BPM = evalin('base','Data_BPM');
            savename = ['Data_BPM_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
            savefolder = [app.SavingfolderLabel.Text,'\'];
            if strcmp(savefolder, 'Saving folder')
                savefolder = [];
            end
            save_data_files(savefolder)
            % save_DataBPM_ and_DataInfo(Data_BPM, DataInfo, savename, savefolder)
            % function save_DataBPM(Data_BPM, DataInfo, savefolder, savename)
            save_DataBPM(Data_BPM, DataInfo, savefolder, savename)
        end

        % Button pushed function: SaveAllButton
        function SaveAllButtonPushed(app, event)
            disp('Saving all: including Data & other large variables')
            warning('THIS MIGHT take for a while')
            savefolder = [app.SavingfolderLabel.Text];
            if strcmp(savefolder, 'Saving folder')
                savefolder = [];
            end
            % newsavefolder = save_data_files(savefolder,1,1,1);
            newsavefolder = save_data_files(savefolder,1,1,1,...
                [],[],[],[],[],[],app.SaveDatawithoutaskingCheckBox.Value)
            disp(['Saved in folder: ',newsavefolder])
            if isempty(savefolder)
                app.SavingfolderLabel.Text=newsavefolder;
            end
            
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            savefolder = [app.SavingfolderLabel.Text];
            % Calls 'uigetdir' to obtain the directory location from the user
            if strcmp(savefolder, 'Saving folder')
                path=uigetdir([],'Choose saving directory');
            else
                path=uigetdir(savefolder,'Choose saving directory');
            end
            if isnumeric(path) % if user canceled
                app.SavingfolderLabel.Text='Saving folder';
                warning('Canceled during choosing directory -> setting text field to default "Saving folder"')
            else
                app.SavingfolderLabel.Text=path;  % Sets the label text to be the selected path
            end
            app.Analysis_Environment.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.Analysis_Environment.Visible = 'on';
            
            
        end

        % Callback function
        function SaveData_BPM_summaryButtonPushed(app, event)
            disp('Saving summary of Data_BPM')
            try
                DataInfo = evalin('base','DataInfo');
                Data_BPM_summary = evalin('base','Data_BPM_summary');
                savename = ['Data_BPM_summary_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
                savefolder = [app.SavingfolderLabel.Text,'\'];
                if strcmp(savefolder, 'Saving folder')
                    savefolder = [];
                end
                save_data_files(savefolder)
                % save_DataBPMsummary_and_DataInfo(Data_BPM_summary, DataInfo, savefolder, savename)
                % function save_DataBPMsummary(Data_BPM_summary, DataInfo, savefolder, savename)
                % save_DataBPMsummary(Data_BPM_summary, DataInfo, savefolder, savename)
            catch
                warning('No Data_BPM_summary on workspace! -> not saved')
            end
        end

        % Button pushed function: set_signal_typeButton
        function set_signal_typeButtonPushed(app, event)
            disp('Settings signal type')
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            dat_info = read_variable_from_ws(app,'DataInfo');
            dat_info = set_signal_type(dat_info,filenums,cols,app.SignalTypeDropDown.Value);
            % function [DataInfo] = set_signal_type(DataInfo,file_index,datacolumns,signal_type)
            update_ws_variable(app, 'DataInfo',dat_info)
        end

        % Button pushed function: PlotsignalupsidedownButton
        function PlotsignalupsidedownButtonPushed(app, event)
            disp('Plotting signal upside down')
            % filenum = app.filenums;cols = app.datacolumns;
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            try
                dat = evalin('base','Data');
            catch
                error('No Data found')
            end
            try
                datbpm = evalin('base','Data_BPM');
                bpm_found = 1;
            catch
                bpm_found = 0;
                disp('No peaks Data_BPM found, plotting only raw data')
            end
            %  plot_signal_upside_down(Data, Data_BPM, file_index,datacolumns)
            if bpm_found == 1
                plot_signal_upside_down(dat, datbpm,filenums,cols);
            else
                plot_signal_upside_down(dat, [],filenums,cols);
            end
        end

        % Button pushed function: PlotRAWButton
        function PlotRAWButtonPushed(app, event)
            disp('Plotting raw data defined in filenums and columns')
            try
                dat = evalin('base','Data');
            catch
                error('No Data found')
            end
            
            try
                datainfo = evalin('base','DataInfo');
            catch
                error('No DataInfo found')
            end
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            plotting_data_files_to_same = app.PlotdatafilestosameCheckBox.Value;
            hfigs=[];
            if plotting_data_files_to_same == 0
                for kk = 1:length(filenums)
                    hfigs(end+1,1) = create_figure(app);
                    % pause(0.2)
                    PLOT_DataOnly_no_own_figure(dat,filenums(kk),cols,plotting_data_columns_to_same);
                    zoom on
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else % plot all filenums to same figure
                [fig_parameters] = cal_subfig_parameters(cols);
                sub_fig_rows=fig_parameters(1);
                sub_fig_cols=fig_parameters(2);
                if plotting_data_columns_to_same == 0
                    data_with_legend = [];
                    legs = cell(length(filenums),length(cols));
                else % all plots to one figure without subplots
                    legs = [];
                    data_with_legend = [];
                end
                for kk = 1:length(filenums)
                    file_index = filenums(kk);
                    if kk == 1
                        hfig = create_figure(app);
                    end
                    % assuming data length (#rows) to be same in each data column in one file index
                    time = 0:1/datainfo.framerate(file_index):...
                        (length(dat{file_index}.data(:,1))-1)/datainfo.framerate(file_index);
                    for pp = 1:length(cols)
                        col_index = cols(pp);
                        try
                            leg_start = ['File#', num2str(file_index),'(t=',...
                                num2str(round(datainfo.measurement_time.time_sec(file_index)/3600,1)),'h)'];
                        catch
                            leg_start = ['File#', num2str(file_index)];
                        end
                        if plotting_data_columns_to_same == 0 || length(cols) < 2
                            % if all data columns are plotted to same figure --> no subplots needed
                            % otherwise, plot subplots based on amount of chosen datacolumns
                            subplot(sub_fig_rows,sub_fig_cols,pp)
                            if isfield(datainfo,'MEA_electrode_numbers')
                                title(['El#',num2str(datainfo.MEA_electrode_numbers(col_index))]);
                            else
                                title(['Col#',num2str(datainfo.datacol_numbers(col_index))]);
                            end
                            data_with_legend(kk,pp) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            % legend can be shorter as subplot title tells data column info
                            legs{kk,pp} = [leg_start];% ,', file_index=',num2str(file_index)];
                        else % no subplots: include el/col# in legend
                            data_with_legend(end+1) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            if isfield(datainfo,'MEA_electrode_numbers')
                                legs{end+1} = [leg_start,': El#',num2str(datainfo.MEA_electrode_numbers(col_index))];
                            else
                                legs{end+1} = [leg_start,': Col#',num2str(datainfo.datacol_numbers(col_index))];
                            end
                            
                        end
                        hold all
                        
                        axis tight
                    end % end pp = 1:length(cols)
                end % end for kk = 1:length(filenums)
                sgtitle([datainfo.experiment_name, ' - ', datainfo.measurement_name],...
                    'interpreter','none','fontsize',12)
                if plotting_data_columns_to_same == 0
                    % each subplots with own legends
                    for pp = 1:length(cols)
                        subplot(sub_fig_rows,sub_fig_cols,pp)
                        if length(legs) > 3 % legend text in two columns if more than 3 data
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none','NumColumns',2)
                        else
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none')
                        end
                    end
                else
                    if length(legs) > 3 % legend text in two columns if more than 3 data
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none','NumColumns',2)
                    else
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none')
                    end
                    
                end
                zoom on
            end % end_: plot all filenums to same figure
            zoom on
            
            pause(0.2)
            for kk=length(hfigs):-1:1
                figure(hfigs(kk,1))
            end
        end

        % Callback function
        function SaveDataInfoButtonPushed(app, event)
            disp('Saving DataInfo')
            DataInfo = evalin('base','DataInfo');
            savename = ['DataInfo_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
            savefolder = [app.SavingfolderLabel.Text];% ,'\'];
            if strcmp(savefolder, 'Saving folder')
                savefolder = [];
            end
            save_data_files(savefolder)
            % function save_data_files(savefolder, saving_DataPeaks_mean, saving_DataPeaks,saving_raw_data,...
        end

        % Callback function
        function Save_closeall_plot_nextButtonPushed(app, event)
            disp('Saving DataInfo Data_BPM and Data_BPM_summary separately')
            savefolder = [app.SavingfolderLabel.Text,'\'];
            if strcmp(savefolder, 'Saving folder\')
                savefolder = [pwd,'\'];
            end
            DataInfo = evalin('base','DataInfo');
            savename = ['DataInfo_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
            disp('Saving DataInfo variable')
            save([savefolder, savename] ,'DataInfo')
            disp(['... saved'])
            try
                Data_BPM = evalin('base','Data_BPM');
                savename = ['Data_BPM_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
                disp('Saving Data_BPM variable')
                save([savefolder, savename] ,'Data_BPM')
                disp(['... saved'])
            catch
                fprintf(2,'\nNo Data_BPM on workspace! -> not saved\n')
                %warning('No Data_BPM on workspace! -> not saved')
            end
            try
                Data_BPM_summary = evalin('base','Data_BPM_summary');
                savename = ['Data_BPM_summary_',DataInfo.experiment_name,'_',DataInfo.measurement_name];
                disp('Saving Data_BPM_summary variable')
                save([savefolder, savename] ,'Data_BPM_summary')
                disp(['... saved'])
            catch
                fprintf(2,'\nNo Data_BPM_summary on workspace! -> not saved\n')
                %                 warning('No Data_BPM_summary on workspace! -> not saved')
            end
            disp(['In folder: ',savefolder])
            %%% CLOSING FIGS
            disp('Closing all figures'),  close all
            %%% PLOTTING NEXT --> based on current_filenums
            try
                current_filenum =  evalin('base','current_filenum');
            catch
                current_filenum =  1;
                % assigning('base','current_filenum', current_filenum);
            end
            next_num = current_filenum+1;
            evalin('base', ['current_filenum = ', num2str(next_num), ';'])
            
            disp('Plotting data defined current_filenum in workspace')
            dat = evalin('base','Data');
            datbpm = evalin('base','Data_BPM');
            filenums = next_num; % str2num(app.filenumsEditField.Value);
            cols = app.datacolumns;
            plotting_peak_numbers = app.IcludepeaknumbersinplotCheckBox.Value;
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            hfigs=[];
            for kk = 1:length(filenums)
                hfigs(end+1,1) = create_figure(app);
                PLOT_DataWithPeaks_no_own_fig(dat,datbpm,filenums(kk),cols,plotting_peak_numbers,plotting_data_columns_to_same);
                zoom on
            end
            pause(0.2)
            for kk=length(hfigs):-1:1
                figure(hfigs(kk,1))
            end
            
            
        end

        % Value changed function: columnsEditField
        function columnsEditFieldValueChanged(app, event)
            % str2num is used as can be in vector format 2:5 --> str2double does not work!
            app.datacolumns = str2num(app.columnsEditField.Value);
            app.columnsPeakHandlingEditField.Value = app.columnsEditField.Value;
            
        end

        % Value changed function: filenumsEditField
        function filenumsEditFieldValueChanged(app, event)
            app.filenums = str2num(app.filenumsEditField.Value);
            app.filenumsPeakHandlingEditField.Value = app.filenumsEditField.Value;
        end

        % Value changing function: columnsEditField
        function columnsEditFieldValueChanging(app, event)
            app.datacolumns = str2num(event.Value);
            app.columnsPeakHandlingEditField.Value = event.Value;
        end

        % Value changing function: filenumsEditField
        function filenumsEditFieldValueChanging(app, event)
            app.filenums = str2num(event.Value);
            app.filenumsPeakHandlingEditField.Value = event.Value;
            
        end

        % Button pushed function: ReadrawdataButton
        function ReadrawdataButtonPushed(app, event)
            exp_name = app.experimentnameEditField.Value;
            if isempty(exp_name)
                exp_name = 'test'; % set default
            end
            meas_name = app.measurementnameEditField.Value;
            if isempty(meas_name)
                meas_name = 'mea21001a'; % set default
            end
            meas_date = app.measurementdateEditField.Value;
            if isempty(meas_date)
                meas_date = '2020_09_15'; % set default
            end
            if  strcmp(app.DatatypeButtonGroup.SelectedObject.Text,'MEA')
                mea_layout_name = app.MEALayoutEditField.Value;
                if isempty(mea_layout_name)
                    mea_layout_name = 'MEA_64_electrode_layout.txt'; % default MEA layout
                end
                manually_chosen_mea_electrodes = str2num(app.MEAelectrodesEditField.Value);
                [Data, DataInfo ] = load_raw_mea_data_to_Data_and_DataInfo(exp_name, meas_name, meas_date, app.filetype.Value, mea_layout_name, [], manually_chosen_mea_electrodes);
                % function [Data, DataInfo] = load_raw_mea_data_to_Data_and_DataInfo(exp_name, meas_name, meas_date, file_type, mea_layout_name, clear_workspace, manually_chosen_mea_electrodes)
                [DataInfo.Rule] = set_default_filetype_rules_for_peak_finding(DataInfo.framerate(1), app.DatatypeButtonGroup.SelectedObject.Text);
                disp("TODO: Testaa .abf MEA data load toimii")
            else % non-MEA data
                warning("TODO: load non-mea data")
                datacolumns = app.DatacolumnsEditField.Value;
                if isempty(datacolumns)
                    disp("TODO: valitaan kaikki jos ei valittu sarakkeita")
                end
                
            end
            try
                assignin('base','Data',Data)
                assignin('base','DataInfo',DataInfo)
            catch
            end
        end

        % Button pushed function: find_peaks_with_rulesButton
        function find_peaks_with_rulesButtonPushed(app, event)
            id = 'signal:findpeaks:largeMinPeakHeight';
            warning('off',id), clear id
            max_BPM = app.maxBPMinpeakfind;
            min_dist_ms = 60/max_BPM*1e3;
            min_amplitude = app.minAmptofind;
            dat = read_variable_from_ws(app,'Data');
            datbpm = read_variable_from_ws(app,'Data_BPM');
            datinfo = read_variable_from_ws(app,'DataInfo');
            if max_BPM ~= datinfo.Rule.MaxBPM
                [datinfo] = update_max_bpm_rule(max_BPM);
                disp('Peak find rule related to minimum peak distance updated')
            end
            if min_amplitude ~= datinfo.Rule.MinPeakValue
                datinfo.Rule.MinPeakValue = min_amplitude;
                disp('Peak find rule related to minimum amplitude updated')
            end
            
            [filenums,cols,which_peaks_handled] = read_values_from_app_peak_handling(app);
            data_multiply = 1; % = 1 for high peaks, default, will be changed if needed
            disp([10,'%%%%%%%%%%%%%%',10,'Finding peaks with following rules:'])
            disp(['Min peak distance (ms) = ',num2str(min_dist_ms),10,'Min Amplitude (abs) = ',num2str(min_amplitude)])
            % function [Data_BPM] = find_peaks_in_loop(Data, DataInfo, Rule,filenumbers, datacolumns, data_multiply)
            % Rule should have: .FrameRate .MaxBPM .MinPeakValue
            if which_peaks_handled == 1
                % finding only high peaks
                datbpm = find_peaks_in_loop(dat, datinfo, datinfo.Rule, filenums, cols, data_multiply); % 1 = high peaks
            else % finding only low peaks or both peaks
                % finding low peaks first
                datbpm_neg = find_peaks_in_loop(dat, datinfo, datinfo.Rule, filenums, cols, -data_multiply); % -1 = low peaks
                f=fieldnames(datbpm_neg{filenums(1)});
                for pp = 1:length(filenums)
                    file_index = filenums(pp);
                    for hh=1:length(cols)
                        col_index = cols(hh);
                        for ii = 1:length(f)
                            datbpm{file_index,1}.(f{ii})(col_index,1) = datbpm_neg{file_index,1}.(f{ii})(col_index,1);
                        end
                    end
                end
                
                if which_peaks_handled == 2
                    % finding also high peaks
                    assignin('base','Data_BPM',datbpm) % updating found low peaks to workspace
                    datbpm = find_peaks_in_loop(dat, datinfo, datinfo.Rule, filenums, cols, 1); % 1 = high peaks
                end
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peak
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peak
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            update_ws_variable(app, 'Data_BPM',datbpm) % updating peaks to workspace
            update_ws_variable(app, 'DataInfo',datinfo) % updating DataInfo
            try
                Data_BPM = update_Data_BPM;
            catch
                Data_BPM = update_Data_BPM([],[], 0); % if only lower peaks available
            end
            update_ws_variable(app, 'Data_BPM',Data_BPM)
        end

        % Button pushed function: delete_all_peaksButton
        function delete_all_peaksButtonPushed(app, event)
            disp('deleting peaks')
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            filenum = str2num(app.filenumsPeakHandlingEditField.Value);
            cols = str2num(app.columnsPeakHandlingEditField.Value);
            % filenum = app.filenums;
            % cols = app.datacolumns;
            switch app.whichpeak
                case 'low peaks'
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 'high peaks'
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 'both'
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            % function [Data_BPM] = delete_all_peaks(Data_BPM, index, datacolumns, delete_high_and_low)
            % % 1 -> delete high peaks (default), % 0 (or anything below 1) -> delete low peaks,   % 2 (or anything > 1) -> delete high and low peaks
            [datbpm] = delete_all_peaks(datbpm,filenum,cols,delete_high_and_low); %
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if strcmp(app.whichpeak,'low peaks') % update with low peaks
                datbpm = update_Data_BPM_single_file(filenum, cols, 0,datinfo,datbpm);
            elseif strcmp(app.whichpeak,'high peaks') % update with high peaks
                datbpm = update_Data_BPM_single_file(filenum, cols, 1,datinfo,datbpm);
            else%elseif strcmp(app.whichpeak,'both') % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenum, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Selection changed function: ChoosepeakstohandleButtonGroup
        function ChoosepeakstohandleButtonGroupSelectionChanged(app, event)
            app.whichpeak = app.ChoosepeakstohandleButtonGroup.SelectedObject.Text;
        end

        % Callback function
        function ChoosepeaksButtonGroupSizeChanged(app, event)
            position = app.ChoosepeakstohandleButtonGroup.Position;
            
        end

        % Button pushed function: delete_peaks_with_peaknumberButton
        function delete_peaks_with_peaknumberButtonPushed(app, event)
            peak_numbers = str2num(app.peaknumbersEditField.Value);
            % disp('deleting following peaks')
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app_peak_handling(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Peak numbers to be deleted:')
            disp(app.peaknumbersEditField.Value)
            
            % function [Data_BPM] = delete_peaks_with_peaknumber(Data_BPM,file_index,datacolumns,peak_numbers_to_delete,high_or_low_peaks)
            % [Data_BPM] = delete_peaks_with_peaknumber(Data_BPM,file_index,datacolumns,peak_numbers_to_delete,high_or_low_peaks)
            % default: high_or_low_peaks = 0 --> deleting low peaks, 1 = deleting high peaks
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,0);
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,1);
            end
            % assignin('base','Data_BPM',datbpm)
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update wit low peak
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update wit high peak
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
            assignin('base','DataInfo',datinfo) % updating DataInfo TODO: tarvitaanko?
            
            
        end

        % Button pushed function: delete_peaks_with_timeButton
        function delete_peaks_with_timeButtonPushed(app, event)
            time_range = str2num(app.timerangeEditField.Value);
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app_peak_handling(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Peaks in the following time range will be deleted:')
            disp([min(time_range) max(time_range)])
            
            
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_with_time(datbpm,filenums,cols,time_range,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_with_time(datbpm,filenums,cols,time_range,0);
                [datbpm] = delete_peaks_with_time(datbpm,filenums,cols,time_range,1);
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Button pushed function: delete_peaks_belowButton
        function delete_peaks_belowButtonPushed(app, event)
            min_amplitude = str2num(app.minAmplitudeEditField.Value);
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app_peak_handling(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Low amplitude peaks will be deleted, below:')
            disp([min_amplitude])
            
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_below_value(datbpm,filenums,cols,min_amplitude,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_below_value(datbpm,filenums,cols,min_amplitude,0);
                [datbpm] = delete_peaks_below_value(datbpm,filenums,cols,min_amplitude,1);
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
            
        end

        % Button pushed function: delete_peaks_aboveButton
        function delete_peaks_aboveButtonPushed(app, event)
            max_amplitude = str2num(app.maxAmplitudeEditField.Value);
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app_peak_handling(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Too high amplitude peaks will be deleted, above:')
            disp([max_amplitude])
            
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_above_value(datbpm,filenums,cols,max_amplitude,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_above_value(datbpm,filenums,cols,max_amplitude,0);
                [datbpm] = delete_peaks_above_value(datbpm,filenums,cols,max_amplitude,1);
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peak used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Value changed function: MinPeakDistancemsEditField
        function MinPeakDistancemsEditFieldValueChanged(app, event)
            value = str2num(app.MinPeakDistancemsEditField.Value);
            app.maxBPMinpeakfind = value/1e3*60;
            app.MaxBPMEditField.Value = num2str(app.maxBPMinpeakfind);
        end

        % Value changed function: MaxBPMEditField
        function MaxBPMEditFieldValueChanged(app, event)
            app.maxBPMinpeakfind = str2num(app.MaxBPMEditField.Value);
            app.MinPeakDistancemsEditField.Value = num2str(60/app.maxBPMinpeakfind*1e3);
        end

        % Value changed function: MinPeakAmplitudeabsEditField
        function MinPeakAmplitudeabsEditFieldValueChanged(app, event)
            app.minAmptofind = str2num(app.MinPeakAmplitudeabsEditField.Value);
        end

        % Button pushed function: UpdatefilenumsfromworkspaceButton
        function UpdatefilenumsfromworkspaceButtonPushed(app, event)
            disp('Updating current filenum based on value on workspace')
            try
                current_filenum = evalin('base','current_filenum');
                disp(['Setting current_filenum = ',num2str(current_filenum), ' to filenum field'])
                app.filenums = current_filenum;
                app.filenumsEditField.Value = num2str(current_filenum);
                
            catch
                warning('No current_filenum variable found on base workspace! returning')
                return
            end
            
        end

        % Callback function
        function plot_next_rawPushed(app, event)
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            next_plot_filenum_value = max(filenums)+1;
            last_file_index = evalin('base','DataInfo.files_amount');
            if next_plot_filenum_value > last_file_index
                warning('Last file index, cannot plot next')
            else
                app.filenumsEditField.Value = num2str(next_plot_filenum_value);
                filenumsEditFieldValueChanged(app, event)
                % close all
                PlotRAWButtonPushed(app, event)
            end
        end

        % Button pushed function: plot_every10th_signal
        function plot_every10th_signalButtonPushed(app, event)
            dat = evalin('base','Data');
            datinfo = evalin('base','DataInfo');
            plot_chosen_data_files(dat, datinfo)
            % function [] = plot_chosen_data_files(Data, DataInfo, filenumbers, datacolumns, helping_levels)
        end

        % Button pushed function: find_local_maximaButton
        function find_local_maximaButtonPushed(app, event)
            time_range = str2num(app.TimerangeEditField.Value);
            if any(isinf(time_range)) || isempty(time_range)
                warning('No time range given to find local maximum -> exiting')
                return
            end
            dat = evalin('base','Data');
            try
                datbpm = evalin('base','Data_BPM');
            catch
                datbpm = [];
                disp('no Data_BPM on workspace')
            end
            datinfo = evalin('base','DataInfo');
            
            [file,cols,which_peaks_handled] = read_values_from_app_peak_handling(app);
            % [Data_BPM] = add_peaks_with_time(Data, Data_BPM, DataInfo, file_index, datacolumns, time_range, high_or_low_peak)
            % Update current bpm:  function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            switch which_peaks_handled
                case 0 % low peaks
                    disp('Finding minimum (low peak) between specific time')
                    datbpm = add_peak_with_time(dat, datbpm, datinfo, file, cols, time_range,-1);
                    datbpm = update_Data_BPM_single_file(file, cols, 0, datinfo, datbpm);
                case 1 % high peaks
                    disp('Finding maximum (high peak) between specific time')
                    datbpm = add_peak_with_time(dat, datbpm, datinfo, file, cols, time_range,1);
                    datbpm = update_Data_BPM_single_file(file, cols, 1, datinfo, datbpm);
                case 2 % both peaks
                    disp('Finding maximum (high peak) and minimum (low peak) between specific time')
                    % high
                    datbpm = add_peak_with_time(dat, datbpm, datinfo, file, cols, time_range,1);
                    datbpm = update_Data_BPM_single_file(file, cols, 1, datinfo, datbpm);
                    % low
                    datbpm = add_peak_with_time(dat, datbpm, datinfo, file, cols, time_range,-1);
                    % datbpm = update_Data_BPM_single_file(file, cols, [], datinfo, datbpm);
                    datbpm = update_Data_BPM_single_file(file, cols, 0, datinfo, datbpm);
            end
            % Update current bpm
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
            
        end

        % Button pushed function: plot_random_file
        function plot_random_fileButtonPushed(app, event)
            dat = evalin('base','Data');
            try
                datbpm = evalin('base','Data_BPM');
            catch
                datbpm = [];
                disp('No Data_BPM')
            end
            how_many_plots = app.amount_of_random_files.Value;
            % filenum = unique(sort(randi(max(size(dat)),how_many_plots,1)))';
            if how_many_plots > max(size(dat))
                % if user input asked more plots than there is different data,
                % setting maximum number to data size
                how_many_plots = max(size(dat));
                % update "How many" field
                app.amount_of_random_files.Value = how_many_plots;
            end
            filenums = sort(randperm(max(size(dat)),how_many_plots));
            disp(['Plotting randomly ', num2str(length(filenums)), ' different data'])
            cols = 1:min(size(dat{filenums(1),1}.data));
            plotting_peak_numbers = app.IcludepeaknumbersinplotCheckBox.Value;
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            hfigs = zeros(length(filenums),1);
            for kk = 1:length(filenums)
                hfigs(kk,1) = create_figure(app);
                if isempty(datbpm) % just raw data
                    PLOT_DataOnly_no_own_figure(dat,filenums(kk),cols,plotting_data_columns_to_same);
                else
                    PLOT_DataWithPeaks_no_own_fig(dat,datbpm,filenums(kk),cols,plotting_peak_numbers,plotting_data_columns_to_same)
                end
                zoom on
            end
            
            pause(0.2)
            for kk=length(hfigs):-1:1
                figure(hfigs(kk,1))
            end
        end

        % Button pushed function: 
        % check_peak_distances_from_edgesButton
        function check_peak_distances_from_edgesButtonPushed(app, event)
            warning('TODO')
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            disp('Saving only "small" variables: Excluding Data + other large variables that might take for a while)')
            savefolder = [app.SavingfolderLabel.Text];
            if strcmp(savefolder, 'Saving folder')
                savefolder = [];
            end
            newsavefolder = save_data_files(savefolder);
            % function save_data_files(savefolder, saving_DataPeaks_mean, saving_DataPeaks,saving_raw_data,...
            disp(['Saved in folder: ',newsavefolder])
            if isempty(savefolder)
                app.SavingfolderLabel.Text=newsavefolder;
            end
            
        end

        % Button pushed function: delete_first_peakButton
        function delete_first_peakButtonPushed(app, event)
            peak_numbers = 1;
            disp('deleting First peak')
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app_peak_handling(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('deleting both high and low peaks')
            end
            disp('Peak numbers to be deleted:')
            disp(app.peaknumbersEditField.Value)
            
            % function [Data_BPM] = delete_peaks_with_peaknumber(Data_BPM,file_index,datacolumns,peak_numbers_to_delete,high_or_low_peaks)
            % [Data_BPM] = delete_peaks_with_peaknumber(Data_BPM,file_index,datacolumns,peak_numbers_to_delete,high_or_low_peaks)
            % default: high_or_low_peaks = 0 --> deleting low peaks, 1 = deleting high peaks
            if delete_high_and_low < 2
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,delete_high_and_low); %
            else % if both --> first deleting low, then high
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,0);
                [datbpm] = delete_peaks_with_peaknumber(datbpm,filenums,cols,peak_numbers,1);
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peaks used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Button pushed function: delete_last_peakButton
        function delete_last_peakButtonPushed(app, event)
            disp('Deleting LAST peak')
            datbpm = evalin('base','Data_BPM');
            datinfo = evalin('base','DataInfo');
            [filenums,cols,which_peaks_handled] = read_values_from_app_peak_handling(app);
            switch which_peaks_handled
                case 0
                    delete_high_and_low = 0;
                    disp('Deleting low peaks')
                case 1
                    delete_high_and_low = 1;
                    disp('Deleting high peaks')
                case 2
                    delete_high_and_low = 2;
                    disp('Deleting both high and low peaks')
            end
            % getting each last peak index
            for file_index = filenums
                for datacol_index = cols
                    switch delete_high_and_low
                        case 0 % low peaks
                            try
                                peak_number = length(datbpm{file_index,1}.peak_values_low{datacol_index,1});
                            catch
                                peak_number = [];
                            end
                            disp(['Deleting low peak#',num2str(peak_number),' from Data_BPM{',num2str(file_index),',1}(column=',num2str(datacol_index),')'])
                        case 1 % high peaks
                            try
                                peak_number = length(datbpm{file_index,1}.peak_values_high{datacol_index,1});
                            catch
                                peak_number = [];
                            end
                            disp(['Deleting high peak#',num2str(peak_number),' from Data_BPM{',num2str(file_index),',1}(column=',num2str(datacol_index),')'])
                        case 2 % both
                            try
                                peak_number_low = length(datbpm{file_index,1}.peak_values_low{datacol_index,1});
                            catch
                                peak_number_low = [];
                            end
                            try
                                peak_number_high = length(datbpm{file_index,1}.peak_values_high{datacol_index,1});
                            catch
                                peak_number_high = [];
                            end
                            disp(['Deleting low and high peaks#',num2str(peak_number_low),' & #',num2str(peak_number_high),' from Data_BPM{',...
                                num2str(file_index),',1}(column=',num2str(datacol_index),')'])
                    end
                    
                    % default: high_or_low_peaks = 0 --> deleting low peaks, 1 = deleting high peaks
                    if delete_high_and_low < 2
                        [datbpm] = delete_peaks_with_peaknumber(datbpm,file_index,datacol_index,peak_number,delete_high_and_low); %
                    else % if both --> first deleting low, then high
                        [datbpm] = delete_peaks_with_peaknumber(datbpm,file_index,datacol_index,peak_number_low,0);
                        [datbpm] = delete_peaks_with_peaknumber(datbpm,file_index,datacol_index,peak_number_high,1);
                    end
                end
            end
            % Update current bpm
            % function Data_BPM = update_Data_BPM_single_file(filenums, datacols, using_high_peaks,DataInfo, Data_BPM)
            if which_peaks_handled == 0 % update with low peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 0,datinfo,datbpm);
            elseif which_peaks_handled == 1 % update with high peaks
                datbpm = update_Data_BPM_single_file(filenums, cols, 1,datinfo,datbpm);
            elseif which_peaks_handled == 2 % check which high or low peaks used
                datbpm = update_Data_BPM_single_file(filenums, cols, [],datinfo,datbpm);
            end
            assignin('base','Data_BPM',datbpm) % updating peaks to workspace
        end

        % Button pushed function: clearallexceptButton
        function clearallexceptButtonPushed(app, event)
            vars = app.notremoveEditField.Value;
            disp(['clear all except following variables: ',10, vars])
            command_to_execute = ['clearvars -except ', vars];
            evalin('base', command_to_execute)
        end

        % Button pushed function: ClosechosenfigsButton
        function ClosechosenfigsButtonPushed(app, event)
            vars = str2num(app.FigtocloseEditField.Value);
            disp(['Closing following figures: ',num2str(vars)])
            command_to_execute = ['close([',num2str(vars),'])'];
            try
                evalin('base',command_to_execute)
            catch
                warning(['Check given figure numbers! Could not execute following command: ',command_to_execute])
            end
            
        end

        % Value changed function: DatacolumnsEditField
        function DatacolumnsEditFieldValueChanged(app, event)
            app.DatacolumnsEditField.Value = str2num(app.columnsEditField.Value);
        end

        % Value changing function: DatacolumnsEditField
        function DatacolumnsEditFieldValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: filetype
        function filetypeValueChanged(app, event)
            value = app.filetype.Value.Text;
            disp(value)
        end

        % Selection changed function: DatatypeButtonGroup
        function DatatypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Other')
                app.MEALayoutEditField.Visible = 'off';
                app.MEALayoutEditFieldLabel.Visible = 'off';
                app.MEAelectrodesEditField.Visible = 'off';
                app.MEAelectrodesEditFieldLabel.Visible = 'off';
                % datacolumns button on
                app.DatacolumnsEditField.Visible = 'on';
                app.DatacolumnsEditFieldLabel.Visible = 'on';
            else
                app.MEALayoutEditField.Visible = 'on';
                app.MEALayoutEditFieldLabel.Visible = 'on';
                app.MEAelectrodesEditField.Visible = 'on';
                app.MEAelectrodesEditFieldLabel.Visible = 'on';
                % datacolumns button off
                app.DatacolumnsEditField.Visible = 'off';
                app.DatacolumnsEditFieldLabel.Visible = 'off';
            end
        end

        % Button pushed function: CopyFiguretoclipboardButton
        function CopyFiguretoclipboardButtonPushed(app, event)
            cpfig
        end

        % Button pushed function: LoadmatfilesButton
        function LoadmatfilesButtonPushed(app, event)
            disp('Loading .mat files')
            loadfolder = [app.SavingfolderLabel.Text];
            if strcmp(loadfolder, 'Saving folder')
                loadfolder = [];
            end
            [folder_of_files, filename_list] = ... % , file_type, measurement_type] = ...
                list_files('.mat',loadfolder);
            [file_numbers_to_analyze] = choose_files(filename_list);
            for pp = 1:length(file_numbers_to_analyze)
                filename = [folder_of_files,filename_list{file_numbers_to_analyze(pp)}];
                load(filename);
                temp = whos('-file',filename);
                switch temp.name
                    case 'DataInfo'
                        assignin('base',temp.name, DataInfo);
                        try
                            datacols = 1:length(DataInfo.datacol_numbers);
                            disp('Datacolums updated.')
                        catch
                            datacols = 1;
                        end
                        app.columnsEditField.Value = num2str(datacols);
                        app.datacolumns = datacols;
                        app.columnsPeakHandlingEditField.Value = num2str(datacols);
                    case 'Data'
                        assignin('base',temp.name, Data);
                    case 'Data_BPM'
                        assignin('base',temp.name, Data_BPM);
                    case 'Data_BPM_summary'
                        assignin('base',temp.name, Data_BPM_summary);
                    case 'DataPeaks'
                        assignin('base',temp.name, DataPeaks);
                    case 'DataPeaks_mean'
                        assignin('base',temp.name, DataPeaks_mean);
                    case 'DataPeaks_summary'
                        assignin('base',temp.name, DataPeaks_summary);
                    case 'DataPeaks_o2'
                        assignin('base',temp.name, DataPeaks_o2);
                        
                end
            end
        end

        % Button pushed function: ListfilenamesoffilenumsButton
        function ListfilenamesoffilenumsButtonPushed(app, event)
            try
                datainfo = evalin('base','DataInfo');
            catch
                error('No DataInfo found')
            end
            file_indexes = app.filenums;
            disp([10,'%%%%% Chosen file indexes (=filenums on top left of GUI) are pointing to following files:'])
            for file_index=1:length(file_indexes)
                disp([num2str(file_index),') File#',num2str(file_indexes(file_index)),...
                    ': ',datainfo.file_names{file_indexes(file_index),1}])
            end
        end

        % Selection changed function: SizeButtonGroup
        function SizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SizeButtonGroup.SelectedObject;
            
        end

        % Selection changed function: DatatofilterButtonGroup
        function DatatofilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatatofilterButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'All')
                app.filenumsFilter.Visible = 'off';
                app.filenumsFilter_Label.Visible = 'off';
                app.columnsFilter.Visible = 'off';
                app.columnsFilter_Label.Visible = 'off';
            else % choose
                app.filenumsFilter.Visible = 'on';
                app.filenumsFilter_Label.Visible = 'on';
                app.columnsFilter.Visible = 'on';
                app.columnsFilter_Label.Visible = 'on';
            end
        end

        % Button pushed function: FilterdataButton
        function FilterdataButtonPushed(app, event)
            selectedButton = app.DatatofilterButtonGroup.SelectedObject;
            data = evalin('base','Data');
            if strcmp(selectedButton.Text,'All')
                datinfo = evalin('base','DataInfo');
                filenum = 1:datinfo.files_amount;
                cols = 1:length(datinfo.datacol_numbers);
            else
                % now reading these filenum and cols values from filter parameters
                filenum = str2num(app.filenumsFilter.Value);
                cols = str2num(app.columnsFilter.Value);
            end
            filt1 = str2num(app.filter1EditField.Value);
            filt2 = str2num(app.filter2EditField.Value);
            
            % check if overwriting Data
            overwrite_data = app.OverwriteDataCheckBox.Value;
            if overwrite_data == 1
                warning('This will overwrite data with filtered value!')
                answer = questdlg('Are you sure to overwrite data with filtered data?', ...
                   	'Overwrite data', ...
                   	'Yes','No, create Data_filt','No, create Data_filt');
                % Handle response
                switch answer
                    case 'Yes'
                        disp(['Overwriting data'])
                        data = filter_signal_data(filenum, data, cols, [filt1, filt2]);
                        assignin('base','Data',data)
                    case 'No, create Data_filt'
                        overwrite_data = 0;
                end
            end
            if overwrite_data == 0
                data_filtered = filter_signal_data(filenum, data, cols, [filt1, filt2]);
                assignin('base','Data_filtered',data_filtered)
            end
        end

        % Button pushed function: CreateBPMsummaryButton
        function CreateBPMsummaryButtonPushed(app, event)
            Data_BPM_summary = create_BPM_summary;
            assignin('base','Data_BPM_summary',Data_BPM_summary)
        end

        % Button pushed function: updateData_BPMButton
        function updateData_BPMButtonPushed(app, event)
            try
                Data_BPM = update_Data_BPM;
            catch
                Data_BPM = update_Data_BPM([],[], 0); % if only lower peaks available
            end
            update_ws_variable(app, 'Data_BPM',Data_BPM)
        end

        % Button pushed function: checkirregularbeatingButton
        function checkirregularbeatingButtonPushed(app, event)
            DataInfo = evalin('base','DataInfo');
            Data_BPM_summary = evalin('base','Data_BPM_summary');
            DataInfo.irregular_beating_limit = str2num(app.IrregularlevelEditField.Value)/100;
            assignin('base','DataInfo',DataInfo)
            Data_BPM_summary.irregular_beating_table = index_irregular_beating...
                (DataInfo, Data_BPM_summary, DataInfo.irregular_beating_limit);
            sort(unique(Data_BPM_summary.irregular_beating_table.File_index))
            % check_and_plot_irregular_data % previous
            [irregular_file_indexes, irregular_datacolumn_indexes] = ...
                check_irregular_data(Data_BPM_summary);
            % plot summary of irregular beating files
            plot_summary_of_irregular_data(irregular_file_indexes, ...
                irregular_datacolumn_indexes);
            assignin('base','Data_BPM_summary',Data_BPM_summary)
        end

        % Selection changed function: SethypoxiaButtonGroup
        function SethypoxiaButtonGroupSelectionChanged(app, event)
            selectedButton = app.SethypoxiaButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'file indexes')
                app.hypoxia_start_indexEditField.Visible = 'on';
                app.hypoxia_start_indexEditFieldLabel.Visible = 'on';
                app.hypoxia_end_indexEditField.Visible = 'on';
                app.hypoxia_end_indexEditFieldLabel.Visible = 'on';
                % date time invisible
                app.hypoxia_start_datetimeEditField.Visible = 'off';
                app.hypoxia_start_datetimeEditFieldLabel.Visible = 'off';
                app.hypoxia_end_datetimeEditField.Visible = 'off';
                app.hypoxia_end_datetimeLabel.Visible = 'off';
                app.hypoxia_datatime_textLabel.Visible = 'off';
                app.FindhypoxiaindexesButton.Visible = 'off';
            else % date time
                app.hypoxia_start_indexEditField.Visible = 'off';
                app.hypoxia_start_indexEditFieldLabel.Visible = 'off';
                app.hypoxia_end_indexEditField.Visible = 'off';
                app.hypoxia_end_indexEditFieldLabel.Visible = 'off';
                % date time visible
                app.hypoxia_start_datetimeEditField.Visible = 'on';
                app.hypoxia_start_datetimeEditFieldLabel.Visible = 'on';
                app.hypoxia_end_datetimeEditField.Visible = 'on';
                app.hypoxia_end_datetimeLabel.Visible = 'on';
                app.hypoxia_datatime_textLabel.Visible = 'on';
                app.FindhypoxiaindexesButton.Visible = 'on';
            end
        end

        % Button pushed function: SethypoxiaButton
        function SethypoxiaButtonPushed(app, event)
            selectedButton = app.SethypoxiaButtonGroup.SelectedObject;
            DataInfo = read_variable_from_ws(app,'DataInfo');
            if strcmp(selectedButton.Text,'file indexes')
                [hypoxia_start_index, hypoxia_end_index] = read_hypoxia_start_and_end_indexes(app);
                hypoxia_start_datetime = DataInfo.measurement_time.datetime(hypoxia_start_index);
                hypoxia_end_datetime = DataInfo.measurement_time.datetime(hypoxia_end_index);
                % datInfo = run_hypoxia_info_to_DataInfo(DataInfo, hypoxia_start_index, hypoxia_end_index);
                datInfo = set_hypoxia_info_to_DataInfo_with_datetime(DataInfo,hypoxia_start_datetime, hypoxia_end_datetime);
            else % date time used to find hypoxia indexes
                [hypoxia_start_datetime, hypoxia_end_datetime] = read_hypoxia_start_and_end_datetime(app);
                datInfo = set_hypoxia_info_to_DataInfo_with_datetime(DataInfo,hypoxia_start_datetime, hypoxia_end_datetime);
            end
            
            update_ws_variable(app, 'DataInfo', datInfo)
            
        end

        % Button pushed function: FindhypoxiaindexesButton
        function FindhypoxiaindexesButtonPushed(app, event)
            DataInfo = read_variable_from_ws(app,'DataInfo');
            [hypoxia_start_datetime, hypoxia_end_datetime] = read_hypoxia_start_and_end_datetime(app);
            % now indexes are last indexes before given datetime
            [hypoxia_start_index, hypoxia_end_index] = ...
                get_hypoxia_start_and_end_file_indexes(app, DataInfo, hypoxia_start_datetime, hypoxia_end_datetime);
            disp(['Hypoxia start ',datestr(hypoxia_start_datetime),' => file index#',num2str(hypoxia_start_index)])
            disp(['Hypoxia end ',datestr(hypoxia_end_datetime),' => file index#',num2str(hypoxia_end_index)])
            disp(['Found file indexes',10,num2str([hypoxia_start_index, hypoxia_end_index])])
        end

        % Button pushed function: updateDataInfoButton
        function updateDataInfoButtonPushed(app, event)
            DataInfo = read_variable_from_ws(app,'DataInfo');
            DataInfo = update_DataInfo(DataInfo);
            update_ws_variable(app, 'DataInfo', DataInfo)
        end

        % Button pushed function: turn_signalButton
        function turn_signalButtonPushed(app, event)
            [filenums, cols] = read_filenums_and_datacolumns_parent(app);
            if length(filenums)+length(cols) > 2
                disp('Turning signals upside down')
            else
                disp('Turning signal upside down')
            end
            dat = read_variable_from_ws(app,'Data');
            datinfo = read_variable_from_ws(app,'DataInfo');
            try
                datbpm = read_variable_from_ws(app,'Data_BPM');
                [dat, datbpm, datinfo] = turn_signal_and_Data_BPM(filenums,cols, dat, datinfo, datbpm);
                try
                    datbpm = update_Data_BPM(datinfo,datbpm);
                catch
                    datbpm = update_Data_BPM(datinfo,datbpm,0); % if only lower peaks available
                end
                update_ws_variable(app, 'Data_BPM',datbpm)
            catch
                [dat, ~, datinfo] = turn_signal_and_Data_BPM(filenums,cols, dat, datinfo);
            end
            update_ws_variable(app, 'DataInfo', datinfo);
            update_ws_variable(app, 'Data',dat);
        end

        % Button pushed function: ClearcommandwindowButton
        function ClearcommandwindowButtonPushed(app, event)
            clc
        end

        % Button pushed function: QuickPlotData_BPM_summaryButton
        function QuickPlotData_BPM_summaryButtonPushed(app, event)
            switch app.SummaryplotButtonGroup.SelectedObject.Text
                case 'BPM'
                    bpm_or_amplitude = 'bpm';
                otherwise
                    bpm_or_amplitude = 'amplitude';
            end
            hfig = create_figure(app);
            plot_quick_BPM_summary_plot(bpm_or_amplitude)
            % function plot_quick_BPM_summary_plot(bpm_or_amplitude,DataInfo, Data_BPM_summary)
        end

        % Button pushed function: CreateDataPeaksButton
        function CreateDataPeaksButtonPushed(app, event)
            t_back = app.t_backEditField.Value;
            t_forw = app.t_forwEditField.Value;
            DataPeaks = get_peak_signals([t_back t_forw]);
            update_ws_variable(app, 'DataPeaks', DataPeaks);
            
        end

        % Button pushed function: checkDataPeaksButton
        function checkDataPeaksButtonPushed(app, event)
            disp('Plotting peak forms')
            t_back = app.t_backEditField.Value;
            t_forw = app.t_forwEditField.Value;
            every_nth = app.everynthEditField.Value;
            check_peak_forms([t_back t_forw],every_nth,[],1); % mean plot included
        end

        % Button pushed function: FPstarttdepButton
        function FPstarttdepButtonPushed(app, event)
            % [filenumbers, datacolumns] = read_filenums_and_datacolumns_parent(app);
            % all filenumber and datacolumns
            DataInfo = read_variable_from_ws(app,'DataInfo');
            filenumbers = 1:DataInfo.files_amount;
            datacolumns = 1:length(DataInfo.datacol_numbers);
            
            switch app.first_peak_typeDropDown.Value
                case 'Low'
                    max_or_min_for_peak_value = 'min';
                otherwise
                    max_or_min_for_peak_value = 'max';
            end
            DataPeaks_summary = fpd1_find_first_peak_and_depolarization_amplitude([],[], ...
                max_or_min_for_peak_value, DataInfo);
            used_filter = app.firstfiltertypeDropDown.Value;
            filter_parameters = app.firstfilterparameterSpinner.Value;
            thr = app.fp_start_thresh_pctSpinner.Value/100; % convert percent to ratio
            DataPeaks_summary = fpd2_find_fp_start_and_depolarization_time([],[],...
                used_filter, filter_parameters,thr,[],DataInfo, [], DataPeaks_summary);
            update_ws_variable(app, 'DataPeaks_summary', DataPeaks_summary);
            disp('DataPeaks_summary created.')
        end

        % Button pushed function: CreateDataPeaks_meanButton
        function CreateDataPeaks_meanButtonPushed(app, event)
            DataPeaks = read_variable_from_ws(app,'DataPeaks');
            DataInfo = read_variable_from_ws(app,'DataInfo');
            DataPeaks_mean = get_peak_signal_average(DataPeaks);
            DataPeaks_mean = remove_offset(DataPeaks_mean,DataInfo);%,[t_back t_forw]);
            update_ws_variable(app, 'DataPeaks', DataPeaks)
            update_ws_variable(app, 'DataPeaks_mean', DataPeaks_mean)
        end

        % Button pushed function: Trim_found_peaksButton
        function Trim_found_peaksButtonPushed(app, event)
            DataInfo = read_variable_from_ws(app,'DataInfo');
            Data = read_variable_from_ws(app,'Data');
            Data_BPM = read_variable_from_ws(app,'Data_BPM');
            [filenumbers,datacolumns,which_peaks_handled] = read_values_from_app_peak_handling(app);
            % TODO: which_peaks_handled käytettäisiin määrittämään mitä piikkiä trimmataan
            Data_BPM = trim_peaks_values_and_locations(filenumbers, datacolumns, DataInfo, Data, Data_BPM);
            update_ws_variable(app, 'Data_BPM', Data_BPM)
        end

        % Selection changed function: 
        % PlotDataPeaks_summarysettingsButtonGroup
        function PlotDataPeaks_summarysettingsButtonGroupSelectionChanged(app, event)
            switch app.PlotDataPeaks_summarysettingsButtonGroup.SelectedObject.Text
                case 'Default'
                    % hide plot settings
                    app.yaxisscaleO2EditField.Visible = 'off';
                    app.yaxisscaleO2EditFieldLabel.Visible = 'off';
                    app.yaxisscaleEditField.Visible = 'off';
                    app.yaxisscaleEditFieldLabel.Visible = 'off';
                    app.FPDccorrectionDropDownLabel.Visible = 'off';
                    app.FPDccorrectionDropDown.Visible = 'off';
                    app.timeunitDropDown.Visible = 'off';
                    app.timeunitDropDownLabel.Visible = 'off';
                otherwise % show settings
                    app.yaxisscaleO2EditField.Visible = 'on';
                    app.yaxisscaleO2EditFieldLabel.Visible = 'on';
                    app.yaxisscaleEditField.Visible = 'on';
                    app.yaxisscaleEditFieldLabel.Visible = 'on';
                    app.FPDccorrectionDropDownLabel.Visible = 'on';
                    app.FPDccorrectionDropDown.Visible = 'on';
                    app.timeunitDropDown.Visible = 'on';
                    app.timeunitDropDownLabel.Visible = 'on';
            end
        end

        % Button pushed function: PlotsignalFPDButton
        function PlotsignalFPDButtonPushed(app, event)
            [filenumbers, datacolumns] = read_filenums_and_datacolumns_parent(app);
            % plot_signal_with_fpd_parameters(filenumbers,datacolumns)
            [legs,hplots] = plot_signal_with_fpd_parameters(filenumbers(1),datacolumns);hold all
            if length(filenumbers) > 1
               for pp = filenumbers(2:end)
                   [legs,hplots] = plot_signal_with_fpd_parameters(pp,datacolumns,gcf, [], legs, hplots);
               end
            end
            % [legs,hplots] = plot_signal_with_fpd_parameters(1,1);hold all
            % [legs,hplots] = plot_signal_with_fpd_parameters(10,1,gcf, [], legs,hplots);
            % [legs,hplots] = plot_signal_with_fpd_parameters(22,1,gcf, [], legs,hplots);
        end

        % Button pushed function: PlotRawinMEAlayoutButton
        function PlotRawinMEAlayoutButtonPushed(app, event)
            [filenums,datacolumns] = read_filenums_and_datacolumns_parent(app);
            DataInfo = read_variable_from_ws(app,'DataInfo');
            Data = read_variable_from_ws(app,'Data');
            exp_name_title = [DataInfo.experiment_name,' / ', DataInfo.measurement_name];
            hfigs = [];
            for kk = 1:length(filenums)
                hfigs(end+1,1) = create_figure(app);
                file_index = filenums(kk);
                plot_data_to_subplots_with_layout(Data{file_index,1}.data, ...
                    DataInfo.MEA_electrode_numbers(datacolumns),...
                    DataInfo.framerate(file_index), [], [], [], [], hfigs(end,1));
                zoom on
                % sqtitle_text = [exp_name_title, ': File#', num2str(file_index)];
                sqtitle_text = ['File#', num2str(file_index)];
                sgtitle(sqtitle_text,'interpreter','none','fontsize',12,'Color','red')
            end
            
        end

        % Button pushed function: add_high_peak_from_lowButton
        function add_high_peak_from_lowButtonPushed(app, event)
            [filenums,cols,~] = read_values_from_app_peak_handling(app);
            tstart = app.tp_forwEditField.Value;
            tend = app.tp_backEditField.Value;
            [Data_BPM] = add_local_max_from_low_peak(...
                [tstart tend], filenums, cols);
            update_ws_variable(app, 'Data_BPM', Data_BPM)
            % TODO: include option for peaks_time_range, peak_numbers_range
            % function [Data_BPM] = add_local_max_from_low_peak(...
            %     time_window, file_index, datacolumns,Data, Data_BPM,...
            %   peaks_time_range, peak_numbers_range)
        end

        % Value changed function: DatacolumnsSwitch
        function DatacolumnsSwitchValueChanged(app, event)
            value = app.DatacolumnsSwitch.Value;
            if strcmp(value,'All') % set all columns and hide
                DataInfo = read_variable_from_ws(app,'DataInfo');
                app.columnsEditField.Value = num2str(1:length(DataInfo.datacol_numbers));
                app.columnsEditField_Label.Visible = 'off';
                app.columnsEditField.Visible = 'off';
                app.datacolumns = str2num(app.columnsEditField.Value);
                app.columnsPeakHandlingEditField.Value = app.columnsEditField.Value;
            else % choose datacolumns
                app.columnsEditField_Label.Visible = 'on';
                app.columnsEditField.Visible = 'on';
            end
        end

        % Button pushed function: deletedatafromplotButton
        function deletedatafromplotButtonPushed(app, event)
            linenumbers_to_delete = str2num(app.deletedatalinesEditField.Value);
            disp(['Deleting following lines from current figure: ',...
                app.deletedatalinesEditField.Value])
            delete_certain_lines_from_figure(linenumbers_to_delete)
        end

        % Button pushed function: ClosecurrentfigureButton
        function ClosecurrentfigureButtonPushed(app, event)
            disp('Closing currently activate figure')
            close
        end

        % Button pushed function: PlotnextDataButton
        function PlotnextDataButtonPushed(app, event)
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            next_plot_filenum_index = max(filenums)+1;
            last_file_index = evalin('base','DataInfo.files_amount');
            if next_plot_filenum_index > last_file_index
                warning('Last file index, cannot plot next')
            else
                app.filenumsEditField.Value = num2str(next_plot_filenum_index);
                filenumsEditFieldValueChanged(app, event)
                PlotDataButtonPushed(app, event)
            end
            
            
        end

        % Callback function
        function PlotnextrawButtonPushed(app, event)
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            next_plot_filenum_index = max(filenums)+1;
            last_file_index = evalin('base','DataInfo.files_amount');
            if next_plot_filenum_index > last_file_index
                warning('Last file index, cannot plot next')
            else
                app.filenumsEditField.Value = num2str(next_plot_filenum_index);
                filenumsEditFieldValueChanged(app, event)
                % close all
                PlotRAWButtonPushed(app, event)
            end
        end

        % Button pushed function: Plot_next_raw
        function Plot_next_rawButtonPushed(app, event)
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            next_plot_filenum_index = max(filenums)+1;
            last_file_index = evalin('base','DataInfo.files_amount');
            if next_plot_filenum_index > last_file_index
                warning('Last file index, cannot plot next')
            else
                app.filenumsEditField.Value = num2str(next_plot_filenum_index);
                filenumsEditFieldValueChanged(app, event)
                % close all
                PlotRAWButtonPushed(app, event)
            end
        end

        % Button pushed function: printamoutofpeaksfoundButton
        function printamoutofpeaksfoundButtonPushed(app, event)
            filenums = str2num(app.filenumsEditField.Value);
            DataInfo = read_variable_from_ws(app,'DataInfo');
            Data_BPM = read_variable_from_ws(app,'Data_BPM');
            % disp(['Printing number of peaks in each datacolumn',10])
            % disp(['File#<index> number of peaks: <col1> <col2> ...',10,'...'])
            disp_found_peaks(DataInfo,Data_BPM)
            %             for pp=1:length(filenums)
            %                 file_index = filenums(pp);
            %                 try
            %                     disp(['File#',num2str(file_index),' number of peaks: ',...
            %                         num2str(Data_BPM{file_index, 1}.Amount_of_peaks(:)')])
            %                 catch
            %                     warning('Can not display peaks')
            %                 end
            %             end
        end

        % Button pushed function: PlotsignalFPDButton_2
        function PlotsignalFPDButton_2Pushed(app, event)
            PlotsignalFPDButtonPushed(app, event)
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            switch app.SummaryplotButtonGroup_2.SelectedObject.Text;
                case 'BPM'
                    bpm_or_amplitude = 'bpm';
                otherwise
                    bpm_or_amplitude = 'amplitude';
            end
            hfig = create_figure(app);
            plot_quick_BPM_summary_plot(bpm_or_amplitude)
        end

        % Button pushed function: PlotDataPeaks_summaryButton_2
        function PlotDataPeaks_summaryButton_2Pushed(app, event)
            normalized = app.Normalized_plotCheckBox_2.Value;
            switch app.PlotDataPeaks_summarysettingsButtonGroup_2.SelectedObject.Text
                case 'Default'
                    % default values
                    if str2num(app.Norm_indexesEditField_2.Value) == Inf
                        plot_summary(normalized);
                    else
                        plot_summary(normalized, [], [], [],[], [], [],[],...
                            str2num(app.Norm_indexesEditField_2.Value));
                    end
                otherwise % choose
                    fpd_correction = app.FPDccorrectionDropDown_2.Value;
                    time_unit = app.timeunitDropDown_2.Value;
                    if str2num(app.Norm_indexesEditField_2.Value) == Inf
                        plot_summary(normalized, fpd_correction, time_unit);
                    else
                        plot_summary(normalized, fpd_correction, time_unit, [],[], [], [],[],...
                            str2num(app.Norm_indexesEditField_2.Value));
                    end
                    % scale
                    if any(str2num(app.yaxisscaleEditField_2.Value) ~= [-Inf inf]) || ...
                            any(str2num(app.yaxisscaleO2EditField_2.Value) ~= [-Inf inf])
                        ylimits = str2num(app.yaxisscaleEditField_2.Value);
                        ylimits_o2 = str2num(app.yaxisscaleO2EditField_2.Value);
                        limit_y_axes(ylimits, ylimits_o2)
                    end
            end
        end

        % Selection changed function: 
        % PlotDataPeaks_summarysettingsButtonGroup_2
        function PlotDataPeaks_summarysettingsButtonGroup_2SelectionChanged(app, event)
            % selectedButton = app.PlotDataPeaks_summarysettingsButtonGroup_2.SelectedObject;
            switch app.PlotDataPeaks_summarysettingsButtonGroup_2.SelectedObject.Text
                case 'Default'
                    % hide plot settings
                    app.yaxisscaleO2EditField_2.Visible = 'off';
                    app.yaxisscaleO2EditField_2Label.Visible = 'off';
                    app.yaxisscaleEditField_2.Visible = 'off';
                    app.yaxisscaleEditField_2Label.Visible = 'off';
                    app.FPDccorrectionDropDown_2Label.Visible = 'off';
                    app.FPDccorrectionDropDown_2.Visible = 'off';
                    app.timeunitDropDown_2.Visible = 'off';
                    app.timeunitDropDown_2Label.Visible = 'off';
                otherwise % show settings
                    app.yaxisscaleO2EditField_2.Visible = 'on';
                    app.yaxisscaleO2EditField_2Label.Visible = 'on';
                    app.yaxisscaleEditField_2.Visible = 'on';
                    app.yaxisscaleEditField_2Label.Visible = 'on';
                    app.FPDccorrectionDropDown_2Label.Visible = 'on';
                    app.FPDccorrectionDropDown_2.Visible = 'on';
                    app.timeunitDropDown_2.Visible = 'on';
                    app.timeunitDropDown_2Label.Visible = 'on';
            end
        end

        % Button pushed function: checkDataPeaksButton_2
        function checkDataPeaksButton_2Pushed(app, event)
            disp('Plotting peak forms')
            t_back = app.t_backEditField_2.Value;
            t_forw = app.t_forwEditField_2.Value;
            every_nth = app.everynthEditField_2.Value;
            check_peak_forms([t_back t_forw],every_nth,[],1); % mean plot included
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            % disp('Plotting user set variables')
            xvar = app.xvariableEditField.Value;
            yvar = app.yvariableEditField.Value;
            if isempty(yvar)
                warning('empty y-variable, not plotting, returning')
                return
            end
            disp('Plotting data defined in x- and y-variables: only y-variable is plotted if x is empty')
            if isempty(xvar)
                disp(['x-variable empty, plotting only plot(',yvar,')'])
                try
                    dat = evalin('base',yvar);
                    hfig = create_figure(app);
                    plot(dat)
                catch
                    error(['No ',yvar,' found on workspace'])
                end
            else
                try
                    x = evalin('base',xvar);
                    dat = evalin('base',yvar);
                    hfig = create_figure(app);
                    plot(x,dat)
                catch
                    warning(['plot(',xvar,',',yvar,') not working']), %close
                    return
                end
            end
            legend, zoom on; axis tight
        end

        % Button pushed function: PlottdepButton
        function PlottdepButtonPushed(app, event)
            disp('Plotting depolarization time tdep')
            % function hfig = plot_tdep(normalizing, time_unit, hfig,...
            %     DataInfo, DataPeaks_summary, Data_o2, norm_indexes)
            normalized = app.Normalized_plotCheckBox.Value;
            switch app.PlotDataPeaks_summarysettingsButtonGroup.SelectedObject.Text
                case 'Default'
                    % default values
                    if str2num(app.Norm_indexesEditField.Value) == Inf
                        plot_tdep(normalized);
                    else
                        plot_tdep(normalized, [], [], [], [], [],...
                            str2num(app.Norm_indexesEditField.Value));
                    end
                otherwise % choose
                    time_unit = app.timeunitDropDown.Value;
                    if str2num(app.Norm_indexesEditField.Value) == Inf
                        plot_tdep(normalized, time_unit);
                    else
                        plot_tdep(normalized, time_unit, [],[], [], [],...
                            str2num(app.Norm_indexesEditField.Value));
                    end
                    % scale
                    if any(str2num(app.yaxisscaleEditField.Value) ~= [-Inf inf]) || ...
                            any(str2num(app.yaxisscaleO2EditField.Value) ~= [-Inf inf])
                        ylimits = str2num(app.yaxisscaleEditField.Value);
                        ylimits_o2 = str2num(app.yaxisscaleO2EditField.Value);
                        limit_y_axes(ylimits, ylimits_o2)
                    end
            end
        end

        % Button pushed function: PlottdepButton_2
        function PlottdepButton_2Pushed(app, event)
            disp('Plotting depolarization time tdep')
            % function hfig = plot_tdep(normalizing, time_unit, hfig,...
            %     DataInfo, DataPeaks_summary, Data_o2, norm_indexes)
            normalized = app.Normalized_plotCheckBox_2.Value;
            switch app.PlotDataPeaks_summarysettingsButtonGroup_2.SelectedObject.Text
                case 'Default' % default values
                    if str2num(app.Norm_indexesEditField_2.Value) == Inf
                        % without normalizing
                        plot_tdep(normalized);
                    else % normaling
                        plot_tdep(normalized, [], [], [], [], [],...
                            str2num(app.Norm_indexesEditField_2.Value));
                    end
                otherwise % choose
                    time_unit = app.timeunitDropDown_2.Value;
                    if str2num(app.Norm_indexesEditField_2.Value) == Inf
                        plot_tdep(normalized, time_unit);
                    else
                        plot_tdep(normalized, time_unit, [],[], [], [],...
                            str2num(app.Norm_indexesEditField_2.Value));
                    end
                    % scale
                    if any(str2num(app.yaxisscaleEditField_2.Value) ~= [-Inf inf]) || ...
                            any(str2num(app.yaxisscaleO2EditField_2.Value) ~= [-Inf inf])
                        ylimits = str2num(app.yaxisscaleEditField_2.Value);
                        ylimits_o2 = str2num(app.yaxisscaleO2EditField_2.Value);
                        limit_y_axes(ylimits, ylimits_o2)
                    end
            end
        end

        % Button pushed function: PlotFPsummaryButton
        function PlotFPsummaryButtonPushed(app, event)
            disp('Plotting FP summary')
            % function hfig = plot_fp_summary(normalizing, fpd_correction, time_unit, hfig,...
            %     DataInfo,  DataPeaks_summary, Data_o2, norm_indexes)
            normalized = app.Normalized_plotCheckBox_2.Value;
            switch app.PlotDataPeaks_summarysettingsButtonGroup_2.SelectedObject.Text
                case 'Default' % default values
                    if str2num(app.Norm_indexesEditField_2.Value) == Inf
                        % without normalizing
                        plot_fp_summary(normalized);
                    else % normalizing
                        plot_fp_summary(normalized, [], [], [], [], [], [],...
                            str2num(app.Norm_indexesEditField_2.Value));
                    end
                otherwise % choose values
                    time_unit = app.timeunitDropDown_2.Value;
                    fpd_correction = app.FPDccorrectionDropDown_2.Value;
                    if str2num(app.Norm_indexesEditField_2.Value) == Inf
                        plot_fp_summary(normalized, fpd_correction, time_unit);
                    else
                        plot_fp_summary(normalized, fpd_correction, time_unit, [], [], [], [],...
                            str2num(app.Norm_indexesEditField_2.Value));
                    end
                    % scale
                    if any(str2num(app.yaxisscaleEditField_2.Value) ~= [-Inf inf]) || ...
                            any(str2num(app.yaxisscaleO2EditField_2.Value) ~= [-Inf inf])
                        ylimits = str2num(app.yaxisscaleEditField_2.Value);
                        ylimits_o2 = str2num(app.yaxisscaleO2EditField_2.Value);
                        limit_y_axes(ylimits, ylimits_o2)
                    end
            end
        end

        % Button pushed function: PlotDataPeaks_meanButton
        function PlotDataPeaks_meanButtonPushed(app, event)
            % read parameters from General tab
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            plotting_data_columns_to_same = app.PlotdatacolumnstosameCheckBox.Value;
            plotting_data_files_to_same = app.PlotdatafilestosameCheckBox.Value;
            dat = read_variable_from_ws(app,'DataPeaks_mean');
            disp('Plotting DataPeaks mean signals')
            if length(filenums) == 1
                % if only one filenums is chosen, make plotting_data_files_to_same = 0,
                % no matter what is app.PlotdatafilestosameCheckBox.Value;
                plotting_data_files_to_same = 0;
            end
            
            if plotting_data_files_to_same == 0 % own figures to each selected filenumber
                hfigs=[];
                for kk = 1:length(filenums)
                    hfigs(end+1,1) = create_figure(app);
                    PLOT_DataOnly_no_own_figure(dat,filenums(kk),cols,plotting_data_columns_to_same);
                    zoom on
                end
                pause(0.2)
                for kk=length(hfigs):-1:1
                    figure(hfigs(kk,1))
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            else % plot all filenums to same figure
                datainfo = read_variable_from_ws(app,'DataInfo');
                [fig_parameters] = cal_subfig_parameters(cols);
                sub_fig_rows=fig_parameters(1);
                sub_fig_cols=fig_parameters(2);
                extra_in_x_axis = 0.2; % for peak numbers
                
                if plotting_data_columns_to_same == 0
                    data_with_legend = [];
                    legs = cell(length(filenums),length(cols));
                else % all plots to one figure without subplots
                    legs = [];
                    data_with_legend = [];
                end
                hfig = create_figure(app);
                for kk = 1:length(filenums)
                    file_index = filenums(kk);
                    for pp = 1:length(cols)
                        col_index = cols(pp);
                        try
                            leg_start = ['File#', num2str(file_index),'(t=',...
                                num2str(round(datainfo.measurement_time.time_sec(file_index)/3600,1)),'h)'];
                        catch
                            leg_start = ['File#', num2str(file_index)];
                        end
                        if plotting_data_columns_to_same == 0 || length(cols) < 2
                            % if all data columns are plotted to same figure --> no subplots needed
                            % otherwise, plot subplots based on amount of chosen datacolumns
                            subplot(sub_fig_rows,sub_fig_cols,pp)
                            if isfield(datainfo,'MEA_electrode_numbers')
                                title(['El#',num2str(datainfo.MEA_electrode_numbers(col_index))]);
                            else
                                title(['Col#',num2str(datainfo.datacol_numbers(col_index))]);
                            end
                            % assuming data length (#rows) to be same in each data column in one file index
                            time = 0:1/datainfo.framerate(file_index):...
                                (length(dat{file_index}.data(:,1))-1)/datainfo.framerate(file_index);
                            data_with_legend(kk,pp) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            legs{kk,pp} = [leg_start];
                        else % no subplots: include el/col# in legend
                            % assuming data length (#rows) to be same in each data column in one file index
                            time = 0:1/datainfo.framerate(file_index):...
                                (length(dat{file_index}.data(:,1))-1)/datainfo.framerate(file_index);
                            data_with_legend(end+1) = plot_data_with_linestyle(dat{file_index}.data(:,col_index),time);
                            if isfield(datainfo,'MEA_electrode_numbers')
                                legs{end+1} = [leg_start,': El#',num2str(datainfo.MEA_electrode_numbers(col_index))];
                            else
                                legs{end+1} = [leg_start,': Col#',num2str(datainfo.datacol_numbers(col_index))];
                            end
                            
                        end % end: no subplots
                        hold all
                        axis tight
                    end % end pp = 1:length(cols)
                end % end kk = 1:length(filenums)
                
                sgtitle(['Mean DataPeaks signals from: ',datainfo.experiment_name, ' - ', datainfo.measurement_name],...
                    'interpreter','none','fontsize',12)
                % legend text written in two columns if more than 3 data plotted
                if plotting_data_columns_to_same == 0
                    % each subplots with own legends
                    for pp = 1:length(cols)
                        subplot(sub_fig_rows,sub_fig_cols,pp)
                        if length(data_with_legend(:,pp)) > 3
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none','NumColumns',2)
                        else
                            legend(data_with_legend(:,pp), legs(:,pp), ...
                                'location', 'best','interpreter','none')
                        end
                    end
                else
                    if length(data_with_legend) > 3
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none','NumColumns',2)
                    else
                        legend(data_with_legend, legs, 'location', 'best',...
                            'interpreter','none')
                    end
                    
                end
                zoom on
            end % end: plot all filenums to same figure
        end

        % Button pushed function: 
        % PlotDataPeaksingivenfilenumsandcolumnsButton
        function PlotDataPeaksingivenfilenumsandcolumnsButtonPushed(app, event)
            t_back = app.t_backEditField_2.Value;
            t_forw = app.t_forwEditField_2.Value;
            [filenums,cols] = read_filenums_and_datacolumns_parent(app);
            include_mean=1; % including mean signal
            slice_and_plot_datapeak_signals([t_back t_forw],filenums,cols,include_mean)
        end

        % Button pushed function: PlotDataPeaks_summaryButton
        function PlotDataPeaks_summaryButtonPushed(app, event)
            normalized = app.Normalized_plotCheckBox.Value;
            switch app.PlotDataPeaks_summarysettingsButtonGroup.SelectedObject.Text
                case 'Default'
                    % default values
                    if str2num(app.Norm_indexesEditField.Value) == Inf
                        plot_summary(normalized);
                    else
                        plot_summary(normalized, [], [], [],[], [], [],[],...
                            str2num(app.Norm_indexesEditField.Value));
                    end
                otherwise % choose
                    fpd_correction = app.FPDccorrectionDropDown.Value;
                    time_unit = app.timeunitDropDown.Value;
                    if str2num(app.Norm_indexesEditField.Value) == Inf
                        plot_summary(normalized, fpd_correction, time_unit);
                    else
                        plot_summary(normalized, fpd_correction, time_unit, [],[], [], [],[],...
                            str2num(app.Norm_indexesEditField.Value));
                    end
                    % scale
                    if any(str2num(app.yaxisscaleEditField.Value) ~= [-Inf inf]) || ...
                            any(str2num(app.yaxisscaleO2EditField.Value) ~= [-Inf inf])
                        ylimits = str2num(app.yaxisscaleEditField.Value);
                        ylimits_o2 = str2num(app.yaxisscaleO2EditField.Value);
                        limit_y_axes(ylimits, ylimits_o2)
                    end
            end
        end

        % Button pushed function: PlotpeakfrequencyButton
        function PlotpeakfrequencyButtonPushed(app, event)
            [~, col_indexes] = read_filenums_and_datacolumns_parent(app);
            normalized = app.Normalized_plotCheckBox_3.Value;
            if app.plottocurrentfigureCheckBox.Value == 1 % plot to current figure
                hfig = gcf;
            else % create new figure
                hfig = create_figure(app);
            end
            % default values, will be changed if chosen different
            time_unit = []; y_unit = [];
            shift_time_seconds = 0;
            normalizing_y_indexes = [];
            if app.Normalized_plotCheckBox_3.Value % if normalized plot ticked
                % if normalization indexes are something else than Inf
                if ~(str2num(app.Norm_indexesEditField_3.Value) == Inf)
                    normalizing_y_indexes = str2num(app.Norm_indexesEditField_3.Value);
                end
            end
            % use default or chosen settings
            switch app.PlotFrequency_settings.SelectedObject.Text
                case 'Choose' % set values
                    time_unit = app.timeunitDropDown_3.Value;
                    y_unit = app.yaxisunitDropDown.Value;
                    shift_time_seconds = str2num(app.shifttimeinsecEditField.Value);
                otherwise % 'Default values
                    % change nothing
            end
            plot_peak_distance_matrix(y_unit, time_unit, normalizing_y_indexes, hfig, ...
                shift_time_seconds, col_indexes)
            % TODO:
            % 1)
            % 2) scale included??!
            % if any(str2num(app.yaxisscaleEditField.Value) ~= [-Inf inf]) || ...
            %         any(str2num(app.yaxisscaleO2EditField.Value) ~= [-Inf inf])
            %     ylimits = str2num(app.yaxisscaleEditField.Value);
            %     ylimits_o2 = str2num(app.yaxisscaleO2EditField.Value);
            %     limit_y_axes(ylimits, ylimits_o2)
            % end
        end

        % Value changed function: Normalized_plotCheckBox_3
        function Normalized_plotCheckBox_3ValueChanged(app, event)
            value = app.Normalized_plotCheckBox_3.Value;
            if value % if checked --> turn these on
                app.Norm_indexesEditField_3Label.Visible = 'on';
                app.Norm_indexesEditField_3.Visible = 'on';
            else
                app.Norm_indexesEditField_3Label.Visible = 'off';
                app.Norm_indexesEditField_3.Visible = 'off';
            end
            
        end

        % Selection changed function: PlotFrequency_settings
        function PlotFrequency_settingsSelectionChanged(app, event)
            selectedButton = app.PlotFrequency_settings.SelectedObject;
            if strcmp(selectedButton.Text,'Choose')
                app.timeunitDropDown_3.Visible = 'on';
                app.timeunitDropDown_3Label.Visible = 'on';
                app.yaxisunitDropDown.Visible = 'on';
                app.yaxisunitDropDownLabel.Visible = 'on';
                app.shifttimeinsecEditField.Visible = 'on';
                app.shifttimeinsecEditFieldLabel.Visible = 'on';
            else
                app.timeunitDropDown_3.Visible = 'off';
                app.timeunitDropDown_3Label.Visible = 'off';
                app.yaxisunitDropDown.Visible = 'off';
                app.yaxisunitDropDownLabel.Visible = 'off';
                app.shifttimeinsecEditField.Visible = 'off';
                app.shifttimeinsecEditFieldLabel.Visible = 'off';
                
            end
        end

        % Button pushed function: removedataButton
        function removedataButtonPushed(app, event)
            disp('Removing data.')
            DataInfo = read_variable_from_ws(app,'DataInfo');
            file_indexes_to_remove = str2num(app.remove_filenumsEditField.Value);
            file_indexes_to_remove = sort(unique(file_indexes_to_remove));
            if file_indexes_to_remove == Inf
                disp('Removing data from all files.')
                file_indexes_to_remove = 1:DataInfo.files_amount;
            end
            datacolumn_indexes_to_remove = str2num(app.remove_columnsEditField.Value);
            datacolumn_indexes_to_remove = sort(unique(datacolumn_indexes_to_remove));
            if datacolumn_indexes_to_remove == Inf
                disp('Removing all data column indexes from chosen file indexes')
                datacolumn_indexes_to_remove = 1:length(DataInfo.datacol_numbers);
            end
            % if user chose to remove ALL files & datacolumns --> ask if sure
            if isequal(datacolumn_indexes_to_remove,1:length(DataInfo.datacol_numbers)) ...
                    &&  isequal(file_indexes_to_remove, 1:DataInfo.files_amount)
                
                answer = questdlg(['Chosen to remove ALL files and datacolumns. ',...
                    10,'This would empty all data. Are you sure?'],...
                    'Delete all','Yes','No','No');
                switch answer
                    case 'No'
                        disp('Not deleting all, returning')
                        return
                    otherwise
                        disp('Deleting all files and datacolumns')
                end
            end
            % remove data
            DataInfo = remove_data_from_DataInfo_variable(file_indexes_to_remove,...
                datacolumn_indexes_to_remove);
            Data = read_variable_from_ws(app,'Data');
            Data = remove_data_from_Data_variable(file_indexes_to_remove, ...
                datacolumn_indexes_to_remove);
            Data_BPM = read_variable_from_ws(app,'Data_BPM');
            Data_BPM = remove_data_from_Data_BPM_variable(file_indexes_to_remove,...
                datacolumn_indexes_to_remove);
            % update workspace variable
            update_ws_variable(app, 'DataInfo', DataInfo)
            update_ws_variable(app, 'Data', Data)
            update_ws_variable(app, 'Data_BPM', Data_BPM)
        end

        % Button pushed function: plot_indivual_irregular_filesButton
        function plot_indivual_irregular_filesButtonPushed(app, event)
            Data_BPM_summary = read_variable_from_ws(app,'Data_BPM_summary');
            [irregular_file_indexes, irregular_datacolumn_indexes] = ...
                check_irregular_data(Data_BPM_summary);
            % plot individual irregular files
            plot_files_with_irregular_beating(irregular_file_indexes,...
                irregular_datacolumn_indexes);
        end

        % Button pushed function: FlatpeakFPDButton
        function FlatpeakFPDButtonPushed(app, event)
            DataInfo = read_variable_from_ws(app,'DataInfo');
            DataPeaks_summary = read_variable_from_ws(app,'DataPeaks_summary');
            filenumbers = 1:DataInfo.files_amount;
            datacolumns = 1:length(DataInfo.datacol_numbers);
            
            switch app.nd_peak_typeDropDown.Value
                case 'Low'
                    max_or_min_for_peak_value = 'min';
                otherwise
                    max_or_min_for_peak_value = 'max';
            end
            used_filter = app.ndfiltertypeDropDown.Value;
            filter_parameters = app.ndfilterparameterSpinner.Value;
            peakloc_start_and_end_indexes = str2num(app.ndpeaktimewindowEditField.Value);
            % find repolarization peak
            DataPeaks_summary = fpd3_find_repolarization_peak(...
                peakloc_start_and_end_indexes, max_or_min_for_peak_value,...
                used_filter, filter_parameters,...
                DataInfo, [], DataPeaks_summary);
            
            thr = app.fp_end_thresh_pctSpinner.Value/100; % convert percent to ratio
            DataPeaks_summary = fpd4_find_fp_end_and_calculate_fpd(...
                [], [], used_filter, filter_parameters, thr, [],...
                DataInfo, [], DataPeaks_summary);
            update_ws_variable(app, 'DataPeaks_summary', DataPeaks_summary);
            disp('DataPeaks_summary updated.')
        end

        % Button pushed function: drawpeaktimewindowButton
        function drawpeaktimewindowButtonPushed(app, event)
            PlotsignalFPDButtonPushed(app, event)
            % plot window
            % https://se.mathworks.com/help/images/use-wait-function-after-drawing-roi-example.html
            label_text = 'Window to search';
            title('Draw repolarization peak window','color','r')
            h = drawrectangle('Label',label_text,'StripeColor','r');
            % h = drawrectangle('Position',[1 1 100 100],'Label',label_text,'StripeColor','r');
            % TODO
% %             pos = customWait(h);
% %             app.ndpeaktimewindowEditField.Value = pos;
% %             % coord = roi.Position;
% %             % disp(['Initial x0 and w: ', num2str(round(roi.Position([1,3])))])
% %             function pos = customWait(hROI)
% %                 
% %                 % Listen for mouse clicks on the ROI
% %                 l = addlistener(hROI,'ROIClicked',@clickCallback);
% %                 
% %                 % Block program execution
% %                 uiwait;
% %                 
% %                 % Remove listener
% %                 delete(l);
% %                 
% %                 % Return the current position
% %                 pos = hROI.Position;
% %                 
% %             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Analysis_Environment = uifigure;
            app.Analysis_Environment.Name = 'Analysis Environment';
            app.TabGroup = uitabgroup(app.Analysis_Environment);
            app.LoadprocessdataTab = uitab(app.TabGroup);
            app.LoadprocessdataTab.Title = 'Load & process data';
            app.ReadrawdataButton = uibutton(app.LoadprocessdataTab, 'push');
            app.ReadrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadrawdataButtonPushed, true);
            app.ReadrawdataButton.Text = 'Read raw data';
            app.filetype = uidropdown(app.LoadprocessdataTab);
            app.filetype.Items = {'.h5', '.abf', '.csv', '.txt', '.atf', '.mat', '.avi'};
            app.filetype.ValueChangedFcn = createCallbackFcn(app, @filetypeValueChanged, true);
            app.filetype.Value = '.h5';
            app.DatacolumnsEditFieldLabel = uilabel(app.LoadprocessdataTab);
            app.DatacolumnsEditFieldLabel.Visible = 'off';
            app.DatacolumnsEditFieldLabel.Text = 'Datacolumns';
            app.DatacolumnsEditField = uieditfield(app.LoadprocessdataTab, 'text');
            app.DatacolumnsEditField.ValueChangedFcn = createCallbackFcn(app, @DatacolumnsEditFieldValueChanged, true);
            app.DatacolumnsEditField.ValueChangingFcn = createCallbackFcn(app, @DatacolumnsEditFieldValueChanging, true);
            app.DatacolumnsEditField.Visible = 'off';
            app.experimentnameEditFieldLabel = uilabel(app.LoadprocessdataTab);
            app.experimentnameEditFieldLabel.Text = 'experiment name';
            app.experimentnameEditField = uieditfield(app.LoadprocessdataTab, 'text');
            app.measurementnameEditFieldLabel = uilabel(app.LoadprocessdataTab);
            app.measurementnameEditFieldLabel.Text = 'measurement name';
            app.measurementnameEditField = uieditfield(app.LoadprocessdataTab, 'text');
            app.measurementdateEditFieldLabel = uilabel(app.LoadprocessdataTab);
            app.measurementdateEditFieldLabel.Text = 'measurement date';
            app.measurementdateEditField = uieditfield(app.LoadprocessdataTab, 'text');
            app.FiletypeLabel = uilabel(app.LoadprocessdataTab);
            app.FiletypeLabel.Text = 'Filetype';
            app.MEALayoutEditFieldLabel = uilabel(app.LoadprocessdataTab);
            app.MEALayoutEditFieldLabel.Text = 'MEA Layout';
            app.MEALayoutEditField = uieditfield(app.LoadprocessdataTab, 'text');
            app.DatatypeButtonGroup = uibuttongroup(app.LoadprocessdataTab);
            app.DatatypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatypeButtonGroupSelectionChanged, true);
            app.DatatypeButtonGroup.Title = 'Data type';
            app.MEAButton = uiradiobutton(app.DatatypeButtonGroup);
            app.MEAButton.Text = 'MEA';
            app.MEAButton.Value = true;
            app.OtherButton = uiradiobutton(app.DatatypeButtonGroup);
            app.OtherButton.Text = 'Other';
            app.MEAelectrodesEditFieldLabel = uilabel(app.LoadprocessdataTab);
            app.MEAelectrodesEditFieldLabel.Text = 'MEA electrodes';
            app.MEAelectrodesEditField = uieditfield(app.LoadprocessdataTab, 'text');
            app.ProcessdatafilteringPanel = uipanel(app.LoadprocessdataTab);
            app.ProcessdatafilteringPanel.Title = 'Process data: filtering';
            app.FilterdataButton = uibutton(app.ProcessdatafilteringPanel, 'push');
            app.FilterdataButton.ButtonPushedFcn = createCallbackFcn(app, @FilterdataButtonPushed, true);
            app.FilterdataButton.Text = 'Filter data';
            app.filenumsFilter_Label = uilabel(app.ProcessdatafilteringPanel);
            app.filenumsFilter_Label.Visible = 'off';
            app.filenumsFilter_Label.Text = 'filenums';
            app.filenumsFilter = uieditfield(app.ProcessdatafilteringPanel, 'text');
            app.filenumsFilter.Visible = 'off';
            app.columnsFilter_Label = uilabel(app.ProcessdatafilteringPanel);
            app.columnsFilter_Label.Visible = 'off';
            app.columnsFilter_Label.Text = 'columns';
            app.columnsFilter = uieditfield(app.ProcessdatafilteringPanel, 'text');
            app.columnsFilter.Visible = 'off';
            app.DatatofilterButtonGroup = uibuttongroup(app.ProcessdatafilteringPanel);
            app.DatatofilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatofilterButtonGroupSelectionChanged, true);
            app.DatatofilterButtonGroup.Title = 'Data to filter';
            app.FilterAllButton = uiradiobutton(app.DatatofilterButtonGroup);
            app.FilterAllButton.Text = 'All';
            app.FilterAllButton.Value = true;
            app.FilterChooseButton = uiradiobutton(app.DatatofilterButtonGroup);
            app.FilterChooseButton.Text = 'Choose';
            app.filter1EditFieldLabel = uilabel(app.ProcessdatafilteringPanel);
            app.filter1EditFieldLabel.Text = 'filter1';
            app.filter1EditField = uieditfield(app.ProcessdatafilteringPanel, 'text');
            app.filter1EditField.Value = '30';
            app.filter2EditFieldLabel = uilabel(app.ProcessdatafilteringPanel);
            app.filter2EditFieldLabel.Text = 'filter2';
            app.filter2EditField = uieditfield(app.ProcessdatafilteringPanel, 'text');
            app.filter2EditField.Value = '100';
            app.FilterwindowsizesLabel = uilabel(app.ProcessdatafilteringPanel);
            app.FilterwindowsizesLabel.Tooltip = {'Filter window size'};
            app.FilterwindowsizesLabel.Text = 'Filter window sizes';
            app.OverwriteDataCheckBox = uicheckbox(app.ProcessdatafilteringPanel);
            app.OverwriteDataCheckBox.Tooltip = {'Overwrites given data with filtered data'; ' otherwise creates new Data_filtered variable'};
            app.OverwriteDataCheckBox.Text = 'Overwrite Data';
            app.medfilt1filterorderNLabel = uilabel(app.ProcessdatafilteringPanel);
            app.medfilt1filterorderNLabel.Tooltip = {'% order of the first filter: moving median'; ' for antipeaks'; '    n_order1 = 30'; ' % ymed1 = medfilt1(rawdata'; 'n_order1)'; ' '; '    % second'; ' moving mean filter for filtered signal'; ' for flat peaks'; '    n_order_filt = 100'; ' % ymed1_fil = smoothdata(ymed1'; '''movmean'''; 'n_order_filt)'; ''};
            app.medfilt1filterorderNLabel.Text = 'medfilt1 filter order N';
            app.Order2ndfiltersmoothdataymed1movmeann_order_filtLabel = uilabel(app.ProcessdatafilteringPanel);
            app.Order2ndfiltersmoothdataymed1movmeann_order_filtLabel.Tooltip = {'% order of the first filter: moving median'; ' for antipeaks'; '    n_order1 = 30'; ' % ymed1 = medfilt1(rawdata'; 'n_order1)'; ' '; '    % second'; ' moving mean filter for filtered signal'; ' for flat peaks'; '    n_order_filt = 100'; ' % ymed1_fil = smoothdata(ymed1'; '''movmean'''; 'n_order_filt)'; ''};
            app.Order2ndfiltersmoothdataymed1movmeann_order_filtLabel.Text = {'Order:2nd filter'; 'smoothdata'; '(ymed1 movmean'''; 'n_order_filt)'};
            app.Noticecurrentlyonlyh5andabfsupportedLabel = uilabel(app.LoadprocessdataTab);
            app.Noticecurrentlyonlyh5andabfsupportedLabel.Tooltip = {'% order of the first filter: moving median'; ' for antipeaks'; '    n_order1 = 30'; ' % ymed1 = medfilt1(rawdata'; 'n_order1)'; ' '; '    % second'; ' moving mean filter for filtered signal'; ' for flat peaks'; '    n_order_filt = 100'; ' % ymed1_fil = smoothdata(ymed1'; '''movmean'''; 'n_order_filt)'; ''};
            app.Noticecurrentlyonlyh5andabfsupportedLabel.Text = 'Notice: currently only .h5 and .abf supported';
            app.SethypoxiainfotoDataInfoPanel = uipanel(app.LoadprocessdataTab);
            app.SethypoxiainfotoDataInfoPanel.Title = 'Set hypoxia info to DataInfo';
            app.hypoxia_datatime_textLabel = uilabel(app.SethypoxiainfotoDataInfoPanel);
            app.hypoxia_datatime_textLabel.Visible = 'off';
            app.hypoxia_datatime_textLabel.Text = {'Give hypoxia start and end time'; 'FORMAT: ''yyyy-MM-dd HH:mm'' or end mm:ss e.g. 2021-04-06 7:32'};
            app.FindhypoxiaindexesButton = uibutton(app.SethypoxiainfotoDataInfoPanel, 'push');
            app.FindhypoxiaindexesButton.ButtonPushedFcn = createCallbackFcn(app, @FindhypoxiaindexesButtonPushed, true);
            app.FindhypoxiaindexesButton.Visible = 'off';
            app.FindhypoxiaindexesButton.Tooltip = {'Find & prints'; 'nearest (earlier) file indexes '; 'based on date times'};
            app.FindhypoxiaindexesButton.Text = {'Find hypoxia '; 'indexes'};
            app.hypoxia_start_indexEditFieldLabel = uilabel(app.SethypoxiainfotoDataInfoPanel);
            app.hypoxia_start_indexEditFieldLabel.Text = 'hypoxia_start_index';
            app.hypoxia_start_indexEditField = uieditfield(app.SethypoxiainfotoDataInfoPanel, 'text');
            app.hypoxia_start_indexEditField.Value = '1';
            app.hypoxia_end_indexEditFieldLabel = uilabel(app.SethypoxiainfotoDataInfoPanel);
            app.hypoxia_end_indexEditFieldLabel.Text = 'hypoxia_end_index';
            app.hypoxia_end_indexEditField = uieditfield(app.SethypoxiainfotoDataInfoPanel, 'text');
            app.hypoxia_end_indexEditField.Value = 'inf';
            app.hypoxia_end_datetimeLabel = uilabel(app.SethypoxiainfotoDataInfoPanel);
            app.hypoxia_end_datetimeLabel.Visible = 'off';
            app.hypoxia_end_datetimeLabel.Tooltip = {'Give in datetime format'; ' e.g.'; ''};
            app.hypoxia_end_datetimeLabel.Text = 'hypoxia end datetime';
            app.hypoxia_end_datetimeEditField = uieditfield(app.SethypoxiainfotoDataInfoPanel, 'text');
            app.hypoxia_end_datetimeEditField.Visible = 'off';
            app.hypoxia_end_datetimeEditField.Value = '2020-09-17 9:12:44';
            app.hypoxia_start_datetimeEditFieldLabel = uilabel(app.SethypoxiainfotoDataInfoPanel);
            app.hypoxia_start_datetimeEditFieldLabel.Visible = 'off';
            app.hypoxia_start_datetimeEditFieldLabel.Text = 'hypoxia start datetime';
            app.hypoxia_start_datetimeEditField = uieditfield(app.SethypoxiainfotoDataInfoPanel, 'text');
            app.hypoxia_start_datetimeEditField.Visible = 'off';
            app.hypoxia_start_datetimeEditField.Value = '2020-09-16 15:12:49';
            app.SethypoxiaButtonGroup = uibuttongroup(app.SethypoxiainfotoDataInfoPanel);
            app.SethypoxiaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SethypoxiaButtonGroupSelectionChanged, true);
            app.SethypoxiaButtonGroup.Title = 'Set hypoxia';
            app.fileindexesButton = uiradiobutton(app.SethypoxiaButtonGroup);
            app.fileindexesButton.Text = 'file indexes';
            app.fileindexesButton.Value = true;
            app.datetimesButton = uiradiobutton(app.SethypoxiaButtonGroup);
            app.datetimesButton.Text = 'date times';
            app.SethypoxiaButton = uibutton(app.SethypoxiainfotoDataInfoPanel, 'push');
            app.SethypoxiaButton.ButtonPushedFcn = createCallbackFcn(app, @SethypoxiaButtonPushed, true);
            app.SethypoxiaButton.Text = {'Set '; 'hypoxia'};
            app.EditdataPanel = uipanel(app.LoadprocessdataTab);
            app.EditdataPanel.Title = 'Edit data';
            app.SignalTypeDropDown = uidropdown(app.EditdataPanel);
            app.SignalTypeDropDown.Items = {'normal_mea', 'low_mea', 'high_mea', 'none', 'normal_ca', 'normal_ap', 'normal_video', 'normal'};
            app.SignalTypeDropDown.Value = 'normal_mea';
            app.set_signal_typeButton = uibutton(app.EditdataPanel, 'push');
            app.set_signal_typeButton.ButtonPushedFcn = createCallbackFcn(app, @set_signal_typeButtonPushed, true);
            app.set_signal_typeButton.Text = 'set_signal_type';
            app.turn_signalButton = uibutton(app.EditdataPanel, 'push');
            app.turn_signalButton.ButtonPushedFcn = createCallbackFcn(app, @turn_signalButtonPushed, true);
            app.turn_signalButton.Text = 'turn_signal';
            app.remove_filenumsEditFieldLabel = uilabel(app.EditdataPanel);
            app.remove_filenumsEditFieldLabel.Text = 'remove_filenums';
            app.remove_filenumsEditField = uieditfield(app.EditdataPanel, 'text');
            app.remove_filenumsEditField.Value = '1';
            app.remove_columnsEditFieldLabel = uilabel(app.EditdataPanel);
            app.remove_columnsEditFieldLabel.Text = 'remove_columns';
            app.remove_columnsEditField = uieditfield(app.EditdataPanel, 'text');
            app.remove_columnsEditField.Value = '1';
            app.removedataButton = uibutton(app.EditdataPanel, 'push');
            app.removedataButton.ButtonPushedFcn = createCallbackFcn(app, @removedataButtonPushed, true);
            app.removedataButton.Text = 'remove data';
            app.PeakHandlingTab = uitab(app.TabGroup);
            app.PeakHandlingTab.Title = 'Peak Handling';
            app.ChoosepeakstohandleButtonGroup = uibuttongroup(app.PeakHandlingTab);
            app.ChoosepeakstohandleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChoosepeakstohandleButtonGroupSelectionChanged, true);
            app.ChoosepeakstohandleButtonGroup.Title = 'Choose peaks to handle';
            app.highpeaksButton = uiradiobutton(app.ChoosepeakstohandleButtonGroup);
            app.highpeaksButton.Text = 'high peaks';
            app.lowpeaksButton = uiradiobutton(app.ChoosepeakstohandleButtonGroup);
            app.lowpeaksButton.Text = 'low peaks';
            app.lowpeaksButton.Value = true;
            app.bothButton = uiradiobutton(app.ChoosepeakstohandleButtonGroup);
            app.bothButton.Text = 'both';
            app.filenumsPeakHandlingEditField_Label = uilabel(app.ChoosepeakstohandleButtonGroup);
            app.filenumsPeakHandlingEditField_Label.Text = 'filenums';
            app.filenumsPeakHandlingEditField = uieditfield(app.ChoosepeakstohandleButtonGroup, 'text');
            app.filenumsPeakHandlingEditField.Value = '1';
            app.columnsPeakHandlingEditField_Label = uilabel(app.ChoosepeakstohandleButtonGroup);
            app.columnsPeakHandlingEditField_Label.Text = 'columns';
            app.columnsPeakHandlingEditField = uieditfield(app.ChoosepeakstohandleButtonGroup, 'text');
            app.columnsPeakHandlingEditField.Value = '1';
            app.RulesandfindpeaksPanel = uipanel(app.PeakHandlingTab);
            app.RulesandfindpeaksPanel.Tooltip = {'default forward time = 0.3 sec if only one value given in Time range'};
            app.RulesandfindpeaksPanel.Title = 'Rules and findpeaks ';
            app.TimerangeEditFieldLabel = uilabel(app.RulesandfindpeaksPanel);
            app.TimerangeEditFieldLabel.Text = 'Time range';
            app.TimerangeEditField = uieditfield(app.RulesandfindpeaksPanel, 'text');
            app.find_local_maximaButton = uibutton(app.RulesandfindpeaksPanel, 'push');
            app.find_local_maximaButton.ButtonPushedFcn = createCallbackFcn(app, @find_local_maximaButtonPushed, true);
            app.find_local_maximaButton.Text = 'find_local_maxima';
            app.find_peaks_with_rulesButton = uibutton(app.RulesandfindpeaksPanel, 'push');
            app.find_peaks_with_rulesButton.ButtonPushedFcn = createCallbackFcn(app, @find_peaks_with_rulesButtonPushed, true);
            app.find_peaks_with_rulesButton.Text = 'find_peaks_with_rules';
            app.MaxBPMLabel = uilabel(app.RulesandfindpeaksPanel);
            app.MaxBPMLabel.Text = 'Max BPM';
            app.MaxBPMEditField = uieditfield(app.RulesandfindpeaksPanel, 'text');
            app.MaxBPMEditField.ValueChangedFcn = createCallbackFcn(app, @MaxBPMEditFieldValueChanged, true);
            app.MaxBPMEditField.Value = '60';
            app.MinPeakDistancemsLabel = uilabel(app.RulesandfindpeaksPanel);
            app.MinPeakDistancemsLabel.Text = 'Min Peak Distance (ms)';
            app.MinPeakDistancemsEditField = uieditfield(app.RulesandfindpeaksPanel, 'text');
            app.MinPeakDistancemsEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakDistancemsEditFieldValueChanged, true);
            app.MinPeakDistancemsEditField.Value = '1000';
            app.MinPeakAmplitudeabsLabel = uilabel(app.RulesandfindpeaksPanel);
            app.MinPeakAmplitudeabsLabel.Text = 'MinPeakAmplitude (abs)';
            app.MinPeakAmplitudeabsEditField = uieditfield(app.RulesandfindpeaksPanel, 'text');
            app.MinPeakAmplitudeabsEditField.ValueChangedFcn = createCallbackFcn(app, @MinPeakAmplitudeabsEditFieldValueChanged, true);
            app.MinPeakAmplitudeabsEditField.Value = '2.5e-5';
            app.DeletepeaksPanel = uipanel(app.PeakHandlingTab);
            app.DeletepeaksPanel.Title = 'Delete peaks';
            app.delete_all_peaksButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_all_peaksButton.ButtonPushedFcn = createCallbackFcn(app, @delete_all_peaksButtonPushed, true);
            app.delete_all_peaksButton.Text = 'delete_all_peaks';
            app.delete_peaks_with_peaknumberButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_peaks_with_peaknumberButton.ButtonPushedFcn = createCallbackFcn(app, @delete_peaks_with_peaknumberButtonPushed, true);
            app.delete_peaks_with_peaknumberButton.Text = 'delete_peaks_with_peaknumber';
            app.peaknumbersEditField = uieditfield(app.DeletepeaksPanel, 'text');
            app.peaknumbersEditField.Value = '1';
            app.peaknumbersEditField_Label = uilabel(app.DeletepeaksPanel);
            app.peaknumbersEditField_Label.Text = 'peaknumbers';
            app.delete_peaks_with_timeButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_peaks_with_timeButton.ButtonPushedFcn = createCallbackFcn(app, @delete_peaks_with_timeButtonPushed, true);
            app.delete_peaks_with_timeButton.Text = 'delete_peaks_with_time';
            app.delete_peaks_with_time_Label = uilabel(app.DeletepeaksPanel);
            app.delete_peaks_with_time_Label.Text = 'timerange';
            app.timerangeEditField = uieditfield(app.DeletepeaksPanel, 'text');
            app.timerangeEditField.Value = '1';
            app.delete_peaks_belowButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_peaks_belowButton.ButtonPushedFcn = createCallbackFcn(app, @delete_peaks_belowButtonPushed, true);
            app.delete_peaks_belowButton.Text = 'delete_peaks_below';
            app.delete_peaks_below_Label = uilabel(app.DeletepeaksPanel);
            app.delete_peaks_below_Label.Text = 'minvalue (abs)';
            app.minAmplitudeEditField = uieditfield(app.DeletepeaksPanel, 'text');
            app.minAmplitudeEditField.Value = '1';
            app.delete_peaks_aboveButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_peaks_aboveButton.ButtonPushedFcn = createCallbackFcn(app, @delete_peaks_aboveButtonPushed, true);
            app.delete_peaks_aboveButton.Text = 'delete_peaks_above';
            app.delete_peaks_above_Label = uilabel(app.DeletepeaksPanel);
            app.delete_peaks_above_Label.Text = 'maxvalue (abs)';
            app.maxAmplitudeEditField = uieditfield(app.DeletepeaksPanel, 'text');
            app.maxAmplitudeEditField.Value = '1';
            app.delete_first_peakButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_first_peakButton.ButtonPushedFcn = createCallbackFcn(app, @delete_first_peakButtonPushed, true);
            app.delete_first_peakButton.Text = 'delete_first_peak';
            app.delete_last_peakButton = uibutton(app.DeletepeaksPanel, 'push');
            app.delete_last_peakButton.ButtonPushedFcn = createCallbackFcn(app, @delete_last_peakButtonPushed, true);
            app.delete_last_peakButton.Text = 'delete_last_peak';
            app.tarvitaankoPanel = uipanel(app.PeakHandlingTab);
            app.tarvitaankoPanel.Title = 'tarvitaanko';
            app.set_main_peaksButton = uibutton(app.tarvitaankoPanel, 'push');
            app.set_main_peaksButton.Enable = 'off';
            app.set_main_peaksButton.Tooltip = {'tarvitaanko'};
            app.set_main_peaksButton.Text = 'set_main_peaks';
            app.define_other_peaks_from_mainButton = uibutton(app.tarvitaankoPanel, 'push');
            app.define_other_peaks_from_mainButton.Enable = 'off';
            app.define_other_peaks_from_mainButton.Tooltip = {'tarvitaanko'};
            app.define_other_peaks_from_mainButton.Text = 'define_other_peaks_from_main';
            app.addpeaks_in_time_rangeButton = uibutton(app.tarvitaankoPanel, 'push');
            app.addpeaks_in_time_rangeButton.Enable = 'off';
            app.addpeaks_in_time_rangeButton.Tooltip = {'tarvitaanko'};
            app.addpeaks_in_time_rangeButton.Text = 'add peaks_in_time_range';
            app.AddpeaksPanel = uipanel(app.PeakHandlingTab);
            app.AddpeaksPanel.Title = 'Add peaks';
            app.add_high_peak_from_lowButton = uibutton(app.AddpeaksPanel, 'push');
            app.add_high_peak_from_lowButton.ButtonPushedFcn = createCallbackFcn(app, @add_high_peak_from_lowButtonPushed, true);
            app.add_high_peak_from_lowButton.Tooltip = {'Adds high peak from low peak position'};
            app.add_high_peak_from_lowButton.Text = 'add_high_peak_from_low';
            app.tp_forwEditFieldLabel = uilabel(app.AddpeaksPanel);
            app.tp_forwEditFieldLabel.Text = 'tp_forw';
            app.tp_forwEditField = uieditfield(app.AddpeaksPanel, 'numeric');
            app.tp_backEditFieldLabel = uilabel(app.AddpeaksPanel);
            app.tp_backEditFieldLabel.Text = 'tp_back';
            app.tp_backEditField = uieditfield(app.AddpeaksPanel, 'numeric');
            app.tp_backEditField.Value = -0.01;
            app.CheckandModifypeaksPanel = uipanel(app.PeakHandlingTab);
            app.CheckandModifypeaksPanel.Title = 'Check and Modify peaks';
            app.Trim_found_peaksButton = uibutton(app.CheckandModifypeaksPanel, 'push');
            app.Trim_found_peaksButton.ButtonPushedFcn = createCallbackFcn(app, @Trim_found_peaksButtonPushed, true);
            app.Trim_found_peaksButton.Tooltip = {'Trims found peaks'; ' check their values and locations by moving mean to eliminate noise-related peak locatons and values'};
            app.Trim_found_peaksButton.Text = 'Trim_found_peaks';
            app.check_peak_distances_from_edgesButton = uibutton(app.CheckandModifypeaksPanel, 'push');
            app.check_peak_distances_from_edgesButton.ButtonPushedFcn = createCallbackFcn(app, @check_peak_distances_from_edgesButtonPushed, true);
            app.check_peak_distances_from_edgesButton.Enable = 'off';
            app.check_peak_distances_from_edgesButton.Visible = 'off';
            app.check_peak_distances_from_edgesButton.Text = 'check_peak_distances_from_edges';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.DataPeaks_summaryPanel = uipanel(app.AnalysisTab);
            app.DataPeaks_summaryPanel.Title = 'DataPeaks_summary';
            app.FPstarttdepButton = uibutton(app.DataPeaks_summaryPanel, 'push');
            app.FPstarttdepButton.ButtonPushedFcn = createCallbackFcn(app, @FPstarttdepButtonPushed, true);
            app.FPstarttdepButton.Text = {'FP-start'; '& tdep'};
            app.PlotDataPeaks_summaryButton = uibutton(app.DataPeaks_summaryPanel, 'push');
            app.PlotDataPeaks_summaryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataPeaks_summaryButtonPushed, true);
            app.PlotDataPeaks_summaryButton.Text = 'Plot DataPeaks_summary';
            app.PlotDataPeaks_summarysettingsButtonGroup = uibuttongroup(app.DataPeaks_summaryPanel);
            app.PlotDataPeaks_summarysettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotDataPeaks_summarysettingsButtonGroupSelectionChanged, true);
            app.PlotDataPeaks_summarysettingsButtonGroup.Tooltip = {'Settings for '; 'Data_BPM summary plot'};
            app.PlotDataPeaks_summarysettingsButtonGroup.Title = 'Plot settings';
            app.Default_DataPeaks_summaryButton = uiradiobutton(app.PlotDataPeaks_summarysettingsButtonGroup);
            app.Default_DataPeaks_summaryButton.Text = 'Default';
            app.Default_DataPeaks_summaryButton.Value = true;
            app.Choose_DataPeaks_summary_Button = uiradiobutton(app.PlotDataPeaks_summarysettingsButtonGroup);
            app.Choose_DataPeaks_summary_Button.Text = 'Choose';
            app.Normalized_plotCheckBox = uicheckbox(app.DataPeaks_summaryPanel);
            app.Normalized_plotCheckBox.Text = 'Normalized_plot';
            app.FPDccorrectionDropDownLabel = uilabel(app.DataPeaks_summaryPanel);
            app.FPDccorrectionDropDownLabel.Visible = 'off';
            app.FPDccorrectionDropDownLabel.Text = 'FPDc correction';
            app.FPDccorrectionDropDown = uidropdown(app.DataPeaks_summaryPanel);
            app.FPDccorrectionDropDown.Items = {'Izumi-Nakaseko', 'Bazett', 'Fridericia', 'none'};
            app.FPDccorrectionDropDown.Visible = 'off';
            app.FPDccorrectionDropDown.Value = 'Izumi-Nakaseko';
            app.timeunitDropDownLabel = uilabel(app.DataPeaks_summaryPanel);
            app.timeunitDropDownLabel.Visible = 'off';
            app.timeunitDropDownLabel.Text = 'time unit';
            app.timeunitDropDown = uidropdown(app.DataPeaks_summaryPanel);
            app.timeunitDropDown.Items = {'datetime', 'hours', 'seconds', 'file_index'};
            app.timeunitDropDown.Visible = 'off';
            app.timeunitDropDown.Value = 'datetime';
            app.yaxisscaleEditFieldLabel = uilabel(app.DataPeaks_summaryPanel);
            app.yaxisscaleEditFieldLabel.Visible = 'off';
            app.yaxisscaleEditFieldLabel.Text = 'y-axis scale';
            app.yaxisscaleEditField = uieditfield(app.DataPeaks_summaryPanel, 'text');
            app.yaxisscaleEditField.Visible = 'off';
            app.yaxisscaleEditField.Value = '-inf inf';
            app.yaxisscaleO2EditFieldLabel = uilabel(app.DataPeaks_summaryPanel);
            app.yaxisscaleO2EditFieldLabel.Visible = 'off';
            app.yaxisscaleO2EditFieldLabel.Text = 'y-axis scale O2';
            app.yaxisscaleO2EditField = uieditfield(app.DataPeaks_summaryPanel, 'text');
            app.yaxisscaleO2EditField.Visible = 'off';
            app.yaxisscaleO2EditField.Value = '-inf inf';
            app.first_peak_typeDropDownLabel = uilabel(app.DataPeaks_summaryPanel);
            app.first_peak_typeDropDownLabel.Text = 'first_peak_type';
            app.first_peak_typeDropDown = uidropdown(app.DataPeaks_summaryPanel);
            app.first_peak_typeDropDown.Items = {'Low', 'High'};
            app.first_peak_typeDropDown.Value = 'Low';
            app.fp_start_thresh_pctSpinnerLabel = uilabel(app.DataPeaks_summaryPanel);
            app.fp_start_thresh_pctSpinnerLabel.Text = 'fp_start_thresh_pct';
            app.fp_start_thresh_pctSpinner = uispinner(app.DataPeaks_summaryPanel);
            app.fp_start_thresh_pctSpinner.Value = 10;
            app.firstfilterparameterSpinnerLabel = uilabel(app.DataPeaks_summaryPanel);
            app.firstfilterparameterSpinnerLabel.Text = 'first filter parameter';
            app.firstfilterparameterSpinner = uispinner(app.DataPeaks_summaryPanel);
            app.firstfilterparameterSpinner.Value = 1500;
            app.firstfiltertypeDropDownLabel = uilabel(app.DataPeaks_summaryPanel);
            app.firstfiltertypeDropDownLabel.Text = 'first filter type';
            app.firstfiltertypeDropDown = uidropdown(app.DataPeaks_summaryPanel);
            app.firstfiltertypeDropDown.Items = {'lowpass_iir', 'moving_average', 'moving_median', 'sgolay', 'rlowess', '', ''};
            app.firstfiltertypeDropDown.Value = 'lowpass_iir';
            app.FlatpeakFPDButton = uibutton(app.DataPeaks_summaryPanel, 'push');
            app.FlatpeakFPDButton.ButtonPushedFcn = createCallbackFcn(app, @FlatpeakFPDButtonPushed, true);
            app.FlatpeakFPDButton.Text = {'Flatpeak'; '& FPD'};
            app.ndfiltertypeDropDownLabel = uilabel(app.DataPeaks_summaryPanel);
            app.ndfiltertypeDropDownLabel.Text = '2nd filter type';
            app.ndfiltertypeDropDown = uidropdown(app.DataPeaks_summaryPanel);
            app.ndfiltertypeDropDown.Items = {'lowpass_iir', 'moving_average', 'moving_median', 'sgolay', 'rlowess', '', ''};
            app.ndfiltertypeDropDown.Value = 'lowpass_iir';
            app.fp_end_thresh_pctSpinnerLabel = uilabel(app.DataPeaks_summaryPanel);
            app.fp_end_thresh_pctSpinnerLabel.Text = 'fp_end_thresh_pct';
            app.fp_end_thresh_pctSpinner = uispinner(app.DataPeaks_summaryPanel);
            app.fp_end_thresh_pctSpinner.Value = 10;
            app.ndfilterparameterSpinnerLabel = uilabel(app.DataPeaks_summaryPanel);
            app.ndfilterparameterSpinnerLabel.Text = '2nd filter parameter';
            app.ndfilterparameterSpinner = uispinner(app.DataPeaks_summaryPanel);
            app.ndfilterparameterSpinner.Value = 200;
            app.nd_peak_typeDropDownLabel = uilabel(app.DataPeaks_summaryPanel);
            app.nd_peak_typeDropDownLabel.Text = '2nd_peak_type';
            app.nd_peak_typeDropDown = uidropdown(app.DataPeaks_summaryPanel);
            app.nd_peak_typeDropDown.Items = {'Low', 'High'};
            app.nd_peak_typeDropDown.Value = 'High';
            app.ndpeaktimewindowEditFieldLabel = uilabel(app.DataPeaks_summaryPanel);
            app.ndpeaktimewindowEditFieldLabel.Text = '2nd peak time window';
            app.ndpeaktimewindowEditField = uieditfield(app.DataPeaks_summaryPanel, 'text');
            app.drawpeaktimewindowButton = uibutton(app.DataPeaks_summaryPanel, 'push');
            app.drawpeaktimewindowButton.ButtonPushedFcn = createCallbackFcn(app, @drawpeaktimewindowButtonPushed, true);
            app.drawpeaktimewindowButton.Text = {'draw peak'; 'time window'};
            app.Norm_indexesEditFieldLabel = uilabel(app.AnalysisTab);
            app.Norm_indexesEditFieldLabel.Text = 'Norm_indexes';
            app.Norm_indexesEditField = uieditfield(app.AnalysisTab, 'text');
            app.Norm_indexesEditField.Value = 'Inf';
            app.FPDtdepPanel = uipanel(app.AnalysisTab);
            app.FPDtdepPanel.Title = 'FPD & tdep';
            app.PlotsignalFPDButton = uibutton(app.FPDtdepPanel, 'push');
            app.PlotsignalFPDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsignalFPDButtonPushed, true);
            app.PlotsignalFPDButton.Text = 'Plot signal FPD';
            app.PlottdepButton = uibutton(app.FPDtdepPanel, 'push');
            app.PlottdepButton.ButtonPushedFcn = createCallbackFcn(app, @PlottdepButtonPushed, true);
            app.PlottdepButton.Tooltip = {'Plot depolarization times'};
            app.PlottdepButton.Text = 'Plot tdep';
            app.BPM_summaryPanel = uipanel(app.AnalysisTab);
            app.BPM_summaryPanel.Title = 'BPM_summary';
            app.QuickPlotData_BPM_summaryButton = uibutton(app.BPM_summaryPanel, 'push');
            app.QuickPlotData_BPM_summaryButton.ButtonPushedFcn = createCallbackFcn(app, @QuickPlotData_BPM_summaryButtonPushed, true);
            app.QuickPlotData_BPM_summaryButton.Text = {'Quick Plot'; 'Data_BPM_summary'};
            app.SummaryplotButtonGroup = uibuttongroup(app.BPM_summaryPanel);
            app.SummaryplotButtonGroup.Tooltip = {'Choose'; ' which summary is plot:'; 'mean BPM or'; 'Amplitude'};
            app.SummaryplotButtonGroup.Title = 'Summary plot';
            app.BPMButton = uiradiobutton(app.SummaryplotButtonGroup);
            app.BPMButton.Text = 'BPM';
            app.BPMButton.Value = true;
            app.AmplitudeButton = uiradiobutton(app.SummaryplotButtonGroup);
            app.AmplitudeButton.Text = 'Amplitude';
            app.checkirregularbeatingButton = uibutton(app.BPM_summaryPanel, 'push');
            app.checkirregularbeatingButton.ButtonPushedFcn = createCallbackFcn(app, @checkirregularbeatingButtonPushed, true);
            app.checkirregularbeatingButton.Text = 'check irregular beating';
            app.IrregularlevelLabel = uilabel(app.BPM_summaryPanel);
            app.IrregularlevelLabel.Text = 'Irregular level (%)';
            app.IrregularlevelEditField = uieditfield(app.BPM_summaryPanel, 'text');
            app.IrregularlevelEditField.Value = '10';
            app.CreateBPMsummaryButton = uibutton(app.BPM_summaryPanel, 'push');
            app.CreateBPMsummaryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBPMsummaryButtonPushed, true);
            app.CreateBPMsummaryButton.Text = 'Create BPM summary';
            app.plot_indivual_irregular_filesButton = uibutton(app.BPM_summaryPanel, 'push');
            app.plot_indivual_irregular_filesButton.ButtonPushedFcn = createCallbackFcn(app, @plot_indivual_irregular_filesButtonPushed, true);
            app.plot_indivual_irregular_filesButton.Text = 'plot_indivual_irregular_files';
            app.DataPeaksPanel = uipanel(app.AnalysisTab);
            app.DataPeaksPanel.Title = 'DataPeaks';
            app.CreateDataPeaksButton = uibutton(app.DataPeaksPanel, 'push');
            app.CreateDataPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDataPeaksButtonPushed, true);
            app.CreateDataPeaksButton.Text = 'Create DataPeaks';
            app.CreateDataPeaks_meanButton = uibutton(app.DataPeaksPanel, 'push');
            app.CreateDataPeaks_meanButton.ButtonPushedFcn = createCallbackFcn(app, @CreateDataPeaks_meanButtonPushed, true);
            app.CreateDataPeaks_meanButton.Text = {'Create '; 'DataPeaks_mean'};
            app.t_backEditFieldLabel = uilabel(app.DataPeaksPanel);
            app.t_backEditFieldLabel.Text = 't_back';
            app.t_backEditField = uieditfield(app.DataPeaksPanel, 'numeric');
            app.t_backEditField.Value = -0.15;
            app.t_forwEditFieldLabel = uilabel(app.DataPeaksPanel);
            app.t_forwEditFieldLabel.Text = 't_forw';
            app.t_forwEditField = uieditfield(app.DataPeaksPanel, 'numeric');
            app.t_forwEditField.Value = 1;
            app.everynthEditFieldLabel = uilabel(app.DataPeaksPanel);
            app.everynthEditFieldLabel.Text = 'every nth';
            app.everynthEditField = uieditfield(app.DataPeaksPanel, 'numeric');
            app.everynthEditField.Value = 10;
            app.checkDataPeaksButton = uibutton(app.DataPeaksPanel, 'push');
            app.checkDataPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @checkDataPeaksButtonPushed, true);
            app.checkDataPeaksButton.Text = {'check '; 'DataPeaks'};
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.WritevariablestoplotPanel = uipanel(app.PlottingTab);
            app.WritevariablestoplotPanel.Title = 'Write variables to plot';
            app.PlotButton_2 = uibutton(app.WritevariablestoplotPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Tooltip = {'Plot data given in x-variable and y-variable.'; 'If x is empty'; ' plots'; 'plot(y-variable)'};
            app.PlotButton_2.Text = 'Plot';
            app.xvariableEditFieldLabel = uilabel(app.WritevariablestoplotPanel);
            app.xvariableEditFieldLabel.Text = 'x-variable';
            app.xvariableEditField = uieditfield(app.WritevariablestoplotPanel, 'text');
            app.yvariableEditFieldLabel = uilabel(app.WritevariablestoplotPanel);
            app.yvariableEditFieldLabel.Text = 'y-variable';
            app.yvariableEditField = uieditfield(app.WritevariablestoplotPanel, 'text');
            app.yvariableEditField.Value = 'data';
            app.SummaryofDataPeaksFPDdepoltimePanel = uipanel(app.PlottingTab);
            app.SummaryofDataPeaksFPDdepoltimePanel.Title = 'Summary of DataPeaks, FPD & depol. time';
            app.PlotDataPeaks_summaryButton_2 = uibutton(app.SummaryofDataPeaksFPDdepoltimePanel, 'push');
            app.PlotDataPeaks_summaryButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotDataPeaks_summaryButton_2Pushed, true);
            app.PlotDataPeaks_summaryButton_2.Text = 'Plot DataPeaks_summary';
            app.PlotDataPeaks_summarysettingsButtonGroup_2 = uibuttongroup(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.PlotDataPeaks_summarysettingsButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @PlotDataPeaks_summarysettingsButtonGroup_2SelectionChanged, true);
            app.PlotDataPeaks_summarysettingsButtonGroup_2.Tooltip = {'Settings for '; 'Data_BPM summary plot'};
            app.PlotDataPeaks_summarysettingsButtonGroup_2.Title = 'Plot settings';
            app.Default_DataPeaks_summaryButton_2 = uiradiobutton(app.PlotDataPeaks_summarysettingsButtonGroup_2);
            app.Default_DataPeaks_summaryButton_2.Text = 'Default';
            app.Default_DataPeaks_summaryButton_2.Value = true;
            app.Choose_DataPeaks_summary_Button_2 = uiradiobutton(app.PlotDataPeaks_summarysettingsButtonGroup_2);
            app.Choose_DataPeaks_summary_Button_2.Text = 'Choose';
            app.Normalized_plotCheckBox_2 = uicheckbox(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.Normalized_plotCheckBox_2.Text = 'Normalized_plot';
            app.FPDccorrectionDropDown_2Label = uilabel(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.FPDccorrectionDropDown_2Label.Visible = 'off';
            app.FPDccorrectionDropDown_2Label.Text = 'FPDc correction';
            app.FPDccorrectionDropDown_2 = uidropdown(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.FPDccorrectionDropDown_2.Items = {'Izumi-Nakaseko', 'Bazett', 'Fridericia', 'none'};
            app.FPDccorrectionDropDown_2.Visible = 'off';
            app.FPDccorrectionDropDown_2.Value = 'Izumi-Nakaseko';
            app.timeunitDropDown_2Label = uilabel(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.timeunitDropDown_2Label.Visible = 'off';
            app.timeunitDropDown_2Label.Text = 'time unit';
            app.timeunitDropDown_2 = uidropdown(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.timeunitDropDown_2.Items = {'datetime', 'hours', 'seconds', 'file_index'};
            app.timeunitDropDown_2.Visible = 'off';
            app.timeunitDropDown_2.Value = 'datetime';
            app.yaxisscaleEditField_2Label = uilabel(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.yaxisscaleEditField_2Label.Visible = 'off';
            app.yaxisscaleEditField_2Label.Text = 'y-axis scale';
            app.yaxisscaleEditField_2 = uieditfield(app.SummaryofDataPeaksFPDdepoltimePanel, 'text');
            app.yaxisscaleEditField_2.Visible = 'off';
            app.yaxisscaleEditField_2.Value = '-inf inf';
            app.yaxisscaleO2EditField_2Label = uilabel(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.yaxisscaleO2EditField_2Label.Visible = 'off';
            app.yaxisscaleO2EditField_2Label.Text = 'y-axis scale O2';
            app.yaxisscaleO2EditField_2 = uieditfield(app.SummaryofDataPeaksFPDdepoltimePanel, 'text');
            app.yaxisscaleO2EditField_2.Visible = 'off';
            app.yaxisscaleO2EditField_2.Value = '-inf inf';
            app.Norm_indexesEditField_2Label = uilabel(app.SummaryofDataPeaksFPDdepoltimePanel);
            app.Norm_indexesEditField_2Label.Text = 'Norm_indexes';
            app.Norm_indexesEditField_2 = uieditfield(app.SummaryofDataPeaksFPDdepoltimePanel, 'text');
            app.Norm_indexesEditField_2.Value = 'Inf';
            app.PlotsignalFPDButton_2 = uibutton(app.SummaryofDataPeaksFPDdepoltimePanel, 'push');
            app.PlotsignalFPDButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotsignalFPDButton_2Pushed, true);
            app.PlotsignalFPDButton_2.Text = 'Plot signal FPD';
            app.PlottdepButton_2 = uibutton(app.SummaryofDataPeaksFPDdepoltimePanel, 'push');
            app.PlottdepButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlottdepButton_2Pushed, true);
            app.PlottdepButton_2.Tooltip = {'Plot depolarization times'; ' uses values given in Plotting summary of DataPeaks panel'};
            app.PlottdepButton_2.Text = 'Plot tdep';
            app.PlotFPsummaryButton = uibutton(app.SummaryofDataPeaksFPDdepoltimePanel, 'push');
            app.PlotFPsummaryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFPsummaryButtonPushed, true);
            app.PlotFPsummaryButton.Tooltip = {'Plot depolarization times'; ' uses values given in Plotting summary of DataPeaks panel'};
            app.PlotFPsummaryButton.Text = 'Plot FP summary';
            app.SummaryofavgbeatingPanel = uipanel(app.PlottingTab);
            app.SummaryofavgbeatingPanel.Title = 'Summary of avg beating';
            app.SummaryplotButtonGroup_2 = uibuttongroup(app.SummaryofavgbeatingPanel);
            app.SummaryplotButtonGroup_2.Tooltip = {'Choose'; ' which summary is plot:'; 'mean BPM or'; 'Amplitude'};
            app.SummaryplotButtonGroup_2.Title = 'Summary plot';
            app.BPMButton_2 = uiradiobutton(app.SummaryplotButtonGroup_2);
            app.BPMButton_2.Text = 'BPM';
            app.BPMButton_2.Value = true;
            app.AmplitudeButton_2 = uiradiobutton(app.SummaryplotButtonGroup_2);
            app.AmplitudeButton_2.Text = 'Amplitude';
            app.PlotButton_4 = uibutton(app.SummaryofavgbeatingPanel, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.DataPeaksPanel_2 = uipanel(app.PlottingTab);
            app.DataPeaksPanel_2.Title = 'DataPeaks';
            app.PlotDataPeaks_meanButton = uibutton(app.DataPeaksPanel_2, 'push');
            app.PlotDataPeaks_meanButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataPeaks_meanButtonPushed, true);
            app.PlotDataPeaks_meanButton.Text = 'Plot DataPeaks_mean';
            app.checkDataPeaksButton_2 = uibutton(app.DataPeaksPanel_2, 'push');
            app.checkDataPeaksButton_2.ButtonPushedFcn = createCallbackFcn(app, @checkDataPeaksButton_2Pushed, true);
            app.checkDataPeaksButton_2.Text = {'check '; 'DataPeaks'};
            app.t_backEditField_2Label = uilabel(app.DataPeaksPanel_2);
            app.t_backEditField_2Label.Text = 't_back';
            app.t_backEditField_2 = uieditfield(app.DataPeaksPanel_2, 'numeric');
            app.t_backEditField_2.Value = -0.15;
            app.t_forwEditField_2Label = uilabel(app.DataPeaksPanel_2);
            app.t_forwEditField_2Label.Text = 't_forw';
            app.t_forwEditField_2 = uieditfield(app.DataPeaksPanel_2, 'numeric');
            app.t_forwEditField_2.Value = 1;
            app.everynthEditField_2Label = uilabel(app.DataPeaksPanel_2);
            app.everynthEditField_2Label.Text = 'every nth';
            app.everynthEditField_2 = uieditfield(app.DataPeaksPanel_2, 'numeric');
            app.everynthEditField_2.Value = 10;
            app.PlotDataPeaksingivenfilenumsandcolumnsButton = uibutton(app.DataPeaksPanel_2, 'push');
            app.PlotDataPeaksingivenfilenumsandcolumnsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataPeaksingivenfilenumsandcolumnsButtonPushed, true);
            app.PlotDataPeaksingivenfilenumsandcolumnsButton.Text = {'Plot DataPeaks'; 'in given filenums and columns'};
            app.PlotpeakorbeatingfrequencyPanel = uipanel(app.PlottingTab);
            app.PlotpeakorbeatingfrequencyPanel.Title = 'Plot peak or beating frequency';
            app.PlotpeakfrequencyButton = uibutton(app.PlotpeakorbeatingfrequencyPanel, 'push');
            app.PlotpeakfrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @PlotpeakfrequencyButtonPushed, true);
            app.PlotpeakfrequencyButton.Text = {'Plot peak '; 'frequency'};
            app.PlotFrequency_settings = uibuttongroup(app.PlotpeakorbeatingfrequencyPanel);
            app.PlotFrequency_settings.SelectionChangedFcn = createCallbackFcn(app, @PlotFrequency_settingsSelectionChanged, true);
            app.PlotFrequency_settings.Tooltip = {'Settings for '; 'plotting peak frequency with running time index'};
            app.PlotFrequency_settings.Title = 'Plot settings';
            app.Default_FreqButton = uiradiobutton(app.PlotFrequency_settings);
            app.Default_FreqButton.Text = 'Default';
            app.Default_FreqButton.Value = true;
            app.Choose_FreqButton = uiradiobutton(app.PlotFrequency_settings);
            app.Choose_FreqButton.Text = 'Choose';
            app.Normalized_plotCheckBox_3 = uicheckbox(app.PlotpeakorbeatingfrequencyPanel);
            app.Normalized_plotCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @Normalized_plotCheckBox_3ValueChanged, true);
            app.Normalized_plotCheckBox_3.Text = 'Normalized_plot';
            app.timeunitDropDown_3Label = uilabel(app.PlotpeakorbeatingfrequencyPanel);
            app.timeunitDropDown_3Label.Visible = 'off';
            app.timeunitDropDown_3Label.Text = 'time unit';
            app.timeunitDropDown_3 = uidropdown(app.PlotpeakorbeatingfrequencyPanel);
            app.timeunitDropDown_3.Items = {'seconds', 'duration', 'datetime', 'hours'};
            app.timeunitDropDown_3.Visible = 'off';
            app.timeunitDropDown_3.Value = 'seconds';
            app.Norm_indexesEditField_3Label = uilabel(app.PlotpeakorbeatingfrequencyPanel);
            app.Norm_indexesEditField_3Label.Text = 'Norm_indexes';
            app.Norm_indexesEditField_3 = uieditfield(app.PlotpeakorbeatingfrequencyPanel, 'text');
            app.Norm_indexesEditField_3.Value = 'Inf';
            app.plottocurrentfigureCheckBox = uicheckbox(app.PlotpeakorbeatingfrequencyPanel);
            app.plottocurrentfigureCheckBox.Text = 'plot to current figure';
            app.yaxisunitDropDownLabel = uilabel(app.PlotpeakorbeatingfrequencyPanel);
            app.yaxisunitDropDownLabel.Visible = 'off';
            app.yaxisunitDropDownLabel.Text = 'y-axis unit';
            app.yaxisunitDropDown = uidropdown(app.PlotpeakorbeatingfrequencyPanel);
            app.yaxisunitDropDown.Items = {'BPM', 'frequency', 'peak_distance_in_milliseconds', 'peak_distance_in_seconds'};
            app.yaxisunitDropDown.Visible = 'off';
            app.yaxisunitDropDown.Value = 'BPM';
            app.shifttimeinsecEditFieldLabel = uilabel(app.PlotpeakorbeatingfrequencyPanel);
            app.shifttimeinsecEditFieldLabel.Visible = 'off';
            app.shifttimeinsecEditFieldLabel.Text = 'shift time in sec';
            app.shifttimeinsecEditField = uieditfield(app.PlotpeakorbeatingfrequencyPanel, 'text');
            app.shifttimeinsecEditField.Visible = 'off';
            app.shifttimeinsecEditField.Value = '0';
            app.GeneralPanel = uipanel(app.Analysis_Environment);
            app.GeneralPanel.Title = 'General';
            app.ChoosedataforplottingPanel = uipanel(app.GeneralPanel);
            app.ChoosedataforplottingPanel.Title = 'Choose data for plotting';
            app.IcludepeaknumbersinplotCheckBox = uicheckbox(app.ChoosedataforplottingPanel);
            app.IcludepeaknumbersinplotCheckBox.Text = 'Iclude peak numbers in plot';
            app.IcludepeaknumbersinplotCheckBox.Value = true;
            app.PlotdatacolumnstosameCheckBox = uicheckbox(app.ChoosedataforplottingPanel);
            app.PlotdatacolumnstosameCheckBox.Text = 'Plot data columns to same';
            app.filenumsEditField_Label = uilabel(app.ChoosedataforplottingPanel);
            app.filenumsEditField_Label.Text = 'filenums';
            app.filenumsEditField = uieditfield(app.ChoosedataforplottingPanel, 'text');
            app.filenumsEditField.ValueChangedFcn = createCallbackFcn(app, @filenumsEditFieldValueChanged, true);
            app.filenumsEditField.ValueChangingFcn = createCallbackFcn(app, @filenumsEditFieldValueChanging, true);
            app.filenumsEditField.Value = '1';
            app.columnsEditField_Label = uilabel(app.ChoosedataforplottingPanel);
            app.columnsEditField_Label.Text = 'columns';
            app.columnsEditField = uieditfield(app.ChoosedataforplottingPanel, 'text');
            app.columnsEditField.ValueChangedFcn = createCallbackFcn(app, @columnsEditFieldValueChanged, true);
            app.columnsEditField.ValueChangingFcn = createCallbackFcn(app, @columnsEditFieldValueChanging, true);
            app.columnsEditField.Value = '1';
            app.PlotdatafilestosameCheckBox = uicheckbox(app.ChoosedataforplottingPanel);
            app.PlotdatafilestosameCheckBox.Text = 'Plot data files to same';
            app.DatacolumnsSwitchLabel = uilabel(app.ChoosedataforplottingPanel);
            app.DatacolumnsSwitchLabel.Text = 'Datacolumns';
            app.DatacolumnsSwitch = uiswitch(app.ChoosedataforplottingPanel, 'rocker');
            app.DatacolumnsSwitch.Items = {'choose', 'All'};
            app.DatacolumnsSwitch.ValueChangedFcn = createCallbackFcn(app, @DatacolumnsSwitchValueChanged, true);
            app.DatacolumnsSwitch.Value = 'All';
            app.SaveloadandclearPanel = uipanel(app.GeneralPanel);
            app.SaveloadandclearPanel.Title = 'Save, load, and clear';
            app.ChooseFolderButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.SavingfolderLabel = uilabel(app.SaveloadandclearPanel);
            app.SavingfolderLabel.Text = 'Saving folder';
            app.SaveAllButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Tooltip = {'Notice: Saving especially Data variable might take for a while'};
            app.SaveAllButton.Text = {'Save '; 'All'};
            app.TextArea_4 = uitextarea(app.SaveloadandclearPanel);
            app.TextArea_4.Editable = 'off';
            app.TextArea_4.Value = {'Do NOT save large variables!'; 'Data, DataPeaks, DataPeaks_mean'; 'Use Save All button for that'};
            app.SaveButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'This does NOT SAVE Data!'; 'Use "Save Data" button for that'};
            app.SaveButton.Text = 'Save';
            app.clearallexceptButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.clearallexceptButton.ButtonPushedFcn = createCallbackFcn(app, @clearallexceptButtonPushed, true);
            app.clearallexceptButton.Tooltip = {'Clears unnecessary variables from workspace'; 'Variables given above are kept'};
            app.clearallexceptButton.Text = 'clear all except';
            app.notremoveEditField = uieditfield(app.SaveloadandclearPanel, 'text');
            app.notremoveEditField.Value = 'Data DataInfo Data_BPM Data_BPM_summary Data_o2 DataPeaks DataPeaks_mean DataPeaks_summary';
            app.LoadmatfilesButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.LoadmatfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmatfilesButtonPushed, true);
            app.LoadmatfilesButton.Text = 'Load .mat file(s)';
            app.ClearcommandwindowButton = uibutton(app.SaveloadandclearPanel, 'push');
            app.ClearcommandwindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClearcommandwindowButtonPushed, true);
            app.ClearcommandwindowButton.Text = {'Clear command '; 'window'};
            app.SaveDatawithoutaskingCheckBox = uicheckbox(app.SaveloadandclearPanel);
            app.SaveDatawithoutaskingCheckBox.Text = {'Save Data '; 'without asking'};
            app.PlotPanel = uipanel(app.GeneralPanel);
            app.PlotPanel.Title = 'Plot';
            app.PlotRAWButton = uibutton(app.PlotPanel, 'push');
            app.PlotRAWButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRAWButtonPushed, true);
            app.PlotRAWButton.Text = 'Plot RAW';
            app.PlotDataButton = uibutton(app.PlotPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = {'Plot '; 'Data'};
            app.CloseallButton = uibutton(app.PlotPanel, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = {'Close '; 'all'};
            app.PlotsignalupsidedownButton = uibutton(app.PlotPanel, 'push');
            app.PlotsignalupsidedownButton.ButtonPushedFcn = createCallbackFcn(app, @PlotsignalupsidedownButtonPushed, true);
            app.PlotsignalupsidedownButton.Text = {'Plot signal '; 'upside down'};
            app.plot_every10th_signal = uibutton(app.PlotPanel, 'push');
            app.plot_every10th_signal.ButtonPushedFcn = createCallbackFcn(app, @plot_every10th_signalButtonPushed, true);
            app.plot_every10th_signal.Text = 'plot_every10th_signal';
            app.plot_random_file = uibutton(app.PlotPanel, 'push');
            app.plot_random_file.ButtonPushedFcn = createCallbackFcn(app, @plot_random_fileButtonPushed, true);
            app.plot_random_file.Text = 'Plot random files';
            app.amount_of_random_files = uieditfield(app.PlotPanel, 'numeric');
            app.amount_of_random_files.Tooltip = {'Set here number of plots that wanted.'};
            app.amount_of_random_files.Value = 1;
            app.plotsLabel = uilabel(app.PlotPanel);
            app.plotsLabel.Text = '#plots';
            app.SizeButtonGroup = uibuttongroup(app.PlotPanel);
            app.SizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SizeButtonGroupSelectionChanged, true);
            app.SizeButtonGroup.Title = 'Size';
            app.DefaultsizeButton = uiradiobutton(app.SizeButtonGroup);
            app.DefaultsizeButton.Text = 'Default size';
            app.FullscreenButton = uiradiobutton(app.SizeButtonGroup);
            app.FullscreenButton.Text = 'Full screen';
            app.FullscreenButton.Value = true;
            app.HalfscreenButton = uiradiobutton(app.SizeButtonGroup);
            app.HalfscreenButton.Text = 'Half screen';
            app.FigtocloseEditFieldLabel = uilabel(app.PlotPanel);
            app.FigtocloseEditFieldLabel.Text = 'Fig# to close';
            app.FigtocloseEditField = uieditfield(app.PlotPanel, 'text');
            app.FigtocloseEditField.Tooltip = {'Set Figure numbers that will be closed'};
            app.CopyFiguretoclipboardButton = uibutton(app.PlotPanel, 'push');
            app.CopyFiguretoclipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopyFiguretoclipboardButtonPushed, true);
            app.CopyFiguretoclipboardButton.Tooltip = {'Copies current figure to clipboard'};
            app.CopyFiguretoclipboardButton.Text = {'Copy Figure '; 'to clipboard'};
            app.PlotRawinMEAlayoutButton = uibutton(app.PlotPanel, 'push');
            app.PlotRawinMEAlayoutButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawinMEAlayoutButtonPushed, true);
            app.PlotRawinMEAlayoutButton.Text = 'Plot Raw in MEA layout';
            app.ClosecurrentfigureButton = uibutton(app.PlotPanel, 'push');
            app.ClosecurrentfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ClosecurrentfigureButtonPushed, true);
            app.ClosecurrentfigureButton.Tooltip = {'Closes currently active figure'};
            app.ClosecurrentfigureButton.Text = {'Close'; 'current figure'};
            app.PlotnextDataButton = uibutton(app.PlotPanel, 'push');
            app.PlotnextDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotnextDataButtonPushed, true);
            app.PlotnextDataButton.Text = {'Plot '; 'next Data'};
            app.Plot_next_raw = uibutton(app.PlotPanel, 'push');
            app.Plot_next_raw.ButtonPushedFcn = createCallbackFcn(app, @Plot_next_rawButtonPushed, true);
            app.Plot_next_raw.Text = 'Plot_next_raw';
            app.deletedatalinesEditFieldLabel = uilabel(app.PlotPanel);
            app.deletedatalinesEditFieldLabel.Text = {'delete '; 'datalines '};
            app.deletedatalinesEditField = uieditfield(app.PlotPanel, 'text');
            app.deletedatafromplotButton = uibutton(app.PlotPanel, 'push');
            app.deletedatafromplotButton.ButtonPushedFcn = createCallbackFcn(app, @deletedatafromplotButtonPushed, true);
            app.deletedatafromplotButton.Text = {'delete data'; 'from plot'};
            app.UpdatefilenumsfromworkspaceButton = uibutton(app.GeneralPanel, 'push');
            app.UpdatefilenumsfromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatefilenumsfromworkspaceButtonPushed, true);
            app.UpdatefilenumsfromworkspaceButton.Text = 'Update filenums from workspace';
            app.ListfilenamesoffilenumsButton = uibutton(app.GeneralPanel, 'push');
            app.ListfilenamesoffilenumsButton.ButtonPushedFcn = createCallbackFcn(app, @ListfilenamesoffilenumsButtonPushed, true);
            app.ListfilenamesoffilenumsButton.Tooltip = {'Lists chosen filenums of MATLAB workspace'};
            app.ListfilenamesoffilenumsButton.Text = {'List file names'; '(of filenums)'};
            app.PrintsfilenamesonworkspaceLabel = uilabel(app.GeneralPanel);
            app.PrintsfilenamesonworkspaceLabel.Text = {'Prints filenames '; 'on workspace'};
            app.updateData_BPMButton = uibutton(app.GeneralPanel, 'push');
            app.updateData_BPMButton.ButtonPushedFcn = createCallbackFcn(app, @updateData_BPMButtonPushed, true);
            app.updateData_BPMButton.Text = 'update Data_BPM';
            app.updateDataInfoButton = uibutton(app.GeneralPanel, 'push');
            app.updateDataInfoButton.ButtonPushedFcn = createCallbackFcn(app, @updateDataInfoButtonPushed, true);
            app.updateDataInfoButton.Text = 'update DataInfo';
            app.ClosechosenfigsButton = uibutton(app.GeneralPanel, 'push');
            app.ClosechosenfigsButton.ButtonPushedFcn = createCallbackFcn(app, @ClosechosenfigsButtonPushed, true);
            app.ClosechosenfigsButton.Tooltip = {'Closes those figure numbers that are given in FigNumbers field above'};
            app.ClosechosenfigsButton.Text = {'Close'; 'chosen'; 'figs'};
            app.printamoutofpeaksfoundButton = uibutton(app.GeneralPanel, 'push');
            app.printamoutofpeaksfoundButton.ButtonPushedFcn = createCallbackFcn(app, @printamoutofpeaksfoundButtonPushed, true);
            app.printamoutofpeaksfoundButton.Text = 'print amout of peaks found';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Analysis_environment

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Analysis_Environment)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Analysis_Environment)
        end
    end
end